classdef ART_ADAS_TOOLS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        V14UIFigure                     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_parameter                   matlab.ui.container.Tab
        Panel_GNSS_yaw_angle_compensation  matlab.ui.container.Panel
        Button_calculate_yaw_angle_compensation  matlab.ui.control.Button
        Button_save_yaw_angle_offset    matlab.ui.control.Button
        EditField_MC2_yaw_angle_offset  matlab.ui.control.NumericEditField
        MC2Label_2                      matlab.ui.control.Label
        EditField_MC1_yaw_angle_offset  matlab.ui.control.NumericEditField
        MC1Label_2                      matlab.ui.control.Label
        Panel_record_flag               matlab.ui.container.Panel
        EditField_MC2_task_index        matlab.ui.control.NumericEditField
        MC2Label                        matlab.ui.control.Label
        EditField_MC1_task_index        matlab.ui.control.NumericEditField
        MC1Label                        matlab.ui.control.Label
        Panel_steering_wheel_zero_compensation  matlab.ui.container.Panel
        EditField_MC2_steering_axle_angle_compensation  matlab.ui.control.NumericEditField
        Label_22                        matlab.ui.control.Label
        EditField_MC1_steering_axle_angle_compensation  matlab.ui.control.NumericEditField
        Label_21                        matlab.ui.control.Label
        Button_save_steering_wheel_angle_compensation  matlab.ui.control.Button
        EditField_MC2_steering_wheel_angle_compensation  matlab.ui.control.NumericEditField
        MC2EditFieldLabel               matlab.ui.control.Label
        EditField_MC1_steering_wheel_angle_compensation  matlab.ui.control.NumericEditField
        MC1EditFieldLabel               matlab.ui.control.Label
        Button_calculate_compensation   matlab.ui.control.Button
        Panel_software_update           matlab.ui.container.Panel
        Button_factory_refresh          matlab.ui.control.Button
        Button_after_sale_update        matlab.ui.control.Button
        Panel_camera_A1_compensation    matlab.ui.container.Panel
        Button_save_A1_offset           matlab.ui.control.Button
        EditField_MC2_A1_offset         matlab.ui.control.NumericEditField
        MC2EditField_2Label             matlab.ui.control.Label
        Button_MC2_A1_shift_right       matlab.ui.control.Button
        Button_MC2_A1_shift_left        matlab.ui.control.Button
        EditField_MC1_A1_offset         matlab.ui.control.NumericEditField
        MC1EditField_2Label             matlab.ui.control.Label
        Button_MC1_A1_shift_right       matlab.ui.control.Button
        Button_MC1_A1_shift_left        matlab.ui.control.Button
        Panel_MC1_steering_wheel_zero_set  matlab.ui.container.Panel
        EditField_MC1_steering_wheel_angle  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Button_MC1_steering_wheel_angle_set_zero_old  matlab.ui.control.Button
        Button_MC1_steering_wheel_angle_set_zero  matlab.ui.control.Button
        Panel_camera_A0_compensation    matlab.ui.container.Panel
        Button_save_A0_offset           matlab.ui.control.Button
        EditField_MC2_A0_offset         matlab.ui.control.NumericEditField
        MC2EditField_2Label_2           matlab.ui.control.Label
        Button_MC2_A0_shift_right       matlab.ui.control.Button
        Button_MC2_A0_shift_left        matlab.ui.control.Button
        EditField_MC1_A0_offset         matlab.ui.control.NumericEditField
        MC1EditField_2Label_2           matlab.ui.control.Label
        Button_MC1_A0_shift_right       matlab.ui.control.Button
        Button_MC1_A0_shift_left        matlab.ui.control.Button
        Panel_MC2_steering_wheel_zero_set  matlab.ui.container.Panel
        EditField_MC2_steering_wheel_angle  matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        Button_MC2_steering_wheel_angle_set_zero_old  matlab.ui.control.Button
        Button_MC2_steering_wheel_angle_set_zero  matlab.ui.control.Button
        Tab_input_protocol_check        matlab.ui.container.Tab
        DropDown_input_protocol_check_link_side  matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        Button_export_input_protocol_check_record  matlab.ui.control.Button
        Button_input_protocol_channel_comfirm_zero  matlab.ui.control.Button
        Button_input_protocol_channel_comfirm_min  matlab.ui.control.Button
        Button_input_protocol_channel_comfirm_max  matlab.ui.control.Button
        Button_updata_input_protocol_channel_data  matlab.ui.control.Button
        EditField_nonzero_channel_number  matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        EditField_channel_current_value  matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        EditField_input_protocol_check_exerciser  matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        Button_input_protocol_list_import  matlab.ui.control.Button
        UITable_input_protocol_list     matlab.ui.control.Table
        Tab_output_protocol_check       matlab.ui.container.Tab
        DropDown_output_protocol_check_link_side  matlab.ui.control.DropDown
        Label_7                         matlab.ui.control.Label
        Panel_related_system_input_data_confirm  matlab.ui.container.Panel
        Button_output_protocol_channel_comfirm_zero  matlab.ui.control.Button
        Button_output_protocol_channel_comfirm_min  matlab.ui.control.Button
        Button_output_protocol_channel_comfirm_max  matlab.ui.control.Button
        Panel_output_data_set           matlab.ui.container.Panel
        Button_output_protocol_channel_set_zero  matlab.ui.control.Button
        Button_output_protocol_channel_set_min  matlab.ui.control.Button
        Button_output_protocol_channel_set_max  matlab.ui.control.Button
        UITable_output_protocol_list    matlab.ui.control.Table
        EditField_output_protocol_check_exerciser  matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        Button_export_output_protocol_check_record  matlab.ui.control.Button
        Button_output_protocol_list_import  matlab.ui.control.Button
        Tab_port_data_interaction       matlab.ui.container.Tab
        EditField_selected_port_data_set_value  matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        EditField_selected_port_data_channel_current_value  matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        Button_selected_port_data_channel_unlock  matlab.ui.control.Button
        Button_confirm_set_port_data_value  matlab.ui.control.Button
        Button_updata_port_data_channel_data  matlab.ui.control.Button
        Button_port_data_list_import    matlab.ui.control.Button
        DropDown_port_data_link_side    matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        UITable_port_data_list          matlab.ui.control.Table
        Tab_YongXiu_A0_modify           matlab.ui.container.Tab
        Panel_MC2_camera_A0_compensation  matlab.ui.container.Panel
        EditField_MC2_A0_switch_threshold  matlab.ui.control.NumericEditField
        Label_16                        matlab.ui.control.Label
        Button_save_YongXiu_MC2_A0_offset  matlab.ui.control.Button
        EditField_MC2_A0_s2_offset      matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        Button_MC2_A0_s2_shift_right    matlab.ui.control.Button
        Button_MC2_A0_s2_shift_left     matlab.ui.control.Button
        EditField_MC2_A0_s1_offset      matlab.ui.control.NumericEditField
        Label_13                        matlab.ui.control.Label
        Button_MC2_A0_s1_shift_right    matlab.ui.control.Button
        Button_MC2_A0_s1_shift_left     matlab.ui.control.Button
        Panel_MC1_camera_A0_compensation  matlab.ui.container.Panel
        EditField_MC1_A0_switch_threshold  matlab.ui.control.NumericEditField
        Label_15                        matlab.ui.control.Label
        Button_save_YongXiu_MC1_A0_offset  matlab.ui.control.Button
        EditField_MC1_A0_s2_offset      matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        Button_MC1_A0_s2_shift_right    matlab.ui.control.Button
        Button_MC1_A0_s2_shift_left     matlab.ui.control.Button
        EditField_MC1_A0_s1_offset      matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        Button_MC1_A0_s1_shift_right    matlab.ui.control.Button
        Button_MC1_A0_s1_shift_left     matlab.ui.control.Button
        Tab_reference_trajectory_maintenance  matlab.ui.container.Tab
        Panel_reference_trajectory_index  matlab.ui.container.Panel
        Button_save_reference_trajectory_index  matlab.ui.control.Button
        EditField_MC2_reference_trajectory_index  matlab.ui.control.NumericEditField
        MC2EditFieldLabel_2             matlab.ui.control.Label
        EditField_MC1_reference_trajectory_index  matlab.ui.control.NumericEditField
        MC1EditFieldLabel_2             matlab.ui.control.Label
        Panel_MC2_grid_origin_info      matlab.ui.container.Panel
        EditField_MC2_grid_origin_longitude  matlab.ui.control.NumericEditField
        Label_20                        matlab.ui.control.Label
        EditField_MC2_grid_origin_latitude  matlab.ui.control.NumericEditField
        Label_19                        matlab.ui.control.Label
        Panel_MC1_grid_origin_info      matlab.ui.container.Panel
        EditField_MC1_grid_origin_longitude  matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        EditField_MC1_grid_origin_latitude  matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        Panel_reference_trajectory_update  matlab.ui.container.Panel
        Button_MC2_reference_trajectory_refresh  matlab.ui.control.Button
        Button_MC1_reference_trajectory_refresh  matlab.ui.control.Button
        Label_MC2_lateral_control_software_version  matlab.ui.control.Label
        Label_MC1_lateral_control_software_version  matlab.ui.control.Label
        Label_tram_type                 matlab.ui.control.Label
        Label_MC2_connection_status     matlab.ui.control.Label
        Button_reboot                   matlab.ui.control.Button
        Label_MC1_connection_status     matlab.ui.control.Label
        Button_disconnect               matlab.ui.control.StateButton
        Button_connect                  matlab.ui.control.StateButton
    end


    properties (Access = private)
        MC1_maintenance_tool_driver                     % 面向单次连接的MC1端维护工具驱动对象实例
        MC2_maintenance_tool_driver                     % 面向单次连接的MC2端维护工具驱动对象实例
        MC1_cycle_update_maintenance_tool_driver        % 面向循环更新的MC1端维护工具驱动对象实例（获取实时方向盘转角）
        MC2_cycle_update_maintenance_tool_driver        % 面向循环更新的MC2端维护工具驱动对象实例（获取实时方向盘转角）
        choose_net_port_dialog_app                      % 选择网口对话框app对象
        timer1                                          % 计时器
        ADAS_software_zip_path                          % ADAS软件目标码压缩包路径
        reference_trajectory_zip_path                   % 参考轨迹压缩包路径
        MC1_software_version_number                     % MC1软件版本数值
        MC2_software_version_number                     % MC2软件版本数值
        MC1_debug_part_enable = true                    % MC1端调试部分使能
        MC2_debug_part_enable = true                    % MC2端调试部分使能
        MC1_trajectory_refresh_enable = false           % MC1端轨迹刷新使能
        MC2_trajectory_refresh_enable = false           % MC2端轨迹刷新使能
        MC1_A0_offset_refresh_enable = false            % MC1端轨迹偏差A0刷新使能
        MC2_A0_offset_refresh_enable = false            % MC2端轨迹偏差A0刷新使能
        MC1_A1_offset_refresh_enable = false            % MC1端航向偏差A1刷新使能
        MC2_A1_offset_refresh_enable = false            % MC2端航向偏差A1刷新使能
        MC1_trajectory_index_refresh_enable = false     % MC1端航向偏差A1刷新使能
        MC2_trajectory_index_refresh_enable = false     % MC2端航向偏差A1刷新使能
        MC1_yaw_angle_offset_refresh_enable = false     % MC1端组合惯导横摆偏置刷新使能
        MC2_yaw_angle_offset_refresh_enable = false     % MC2端组合惯导横摆偏置刷新使能
        original_MC1_A1_offset = 0                      % 原始MC1端A1偏置值
        original_MC2_A1_offset = 0                      % 原始MC2端A1偏置值
        original_MC1_A0_offset = 0                      % 原始MC1端A0偏置值
        original_MC2_A0_offset = 0                      % 原始MC2端A0偏置值
        original_MC1_A0_s1_offset = 0                   % 原始MC1端A0第1阶段偏置值
        original_MC2_A0_s1_offset = 0                   % 原始MC2端A0第1阶段偏置值
        original_MC1_A0_s2_offset = 0                   % 原始MC1端A0第2阶段偏置值
        original_MC2_A0_s2_offset = 0                   % 原始MC2端A0第2阶段偏置值
        original_MC1_A0_switch_threshold = 0            % 原始MC1端A0切换阈值
        original_MC2_A0_switch_threshold = 0            % 原始MC2端A0切换阈值
        input_protocol_list_table                       % 输入协议清单
        input_protocol_check_record                     % 输入协议核对记录
        input_protocol_select_row = 0                   % 输入协议选择的行索引
        output_protocol_list_table                      % 输出协议清单
        output_protocol_check_record                    % 输出协议核对记录
        output_protocol_select_row = 0                  % 输出协议选择的行索引
        port_data_list_table                            % 端口临时数据清单
        port_data_select_row = 0                        % 端口临时数据选择的行索引
    end

    properties (Access = public)
        main_figure_lock = false       % 主界面锁定
    end

    methods (Access = private)

        function [] = Disconnect_telnet_connection(app)
            % 更新标签内容
            app.Label_tram_type.Text = '未选择车型';
            app.Label_tram_type.BackgroundColor = [0.65, 0.65, 0.65]; % Gray
            app.Label_MC1_lateral_control_software_version.Text = 'MC1循迹控制软件版本：未知';
            app.Label_MC2_lateral_control_software_version.Text = 'MC2循迹控制软件版本：未知';

            % 关闭定时器
            if strcmp(get(app.timer1, 'Running'), 'on')
                stop(app.timer1);
            end

            % 断开与控制器的Telnet连接
            if true == app.MC1_debug_part_enable
                if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.MC1_maintenance_tool_driver.Telnet_disconnect;
                end
                if isa(app.MC1_cycle_update_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.MC1_cycle_update_maintenance_tool_driver.Telnet_disconnect;
                end
            end
            if true == app.MC2_debug_part_enable
                if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.MC2_maintenance_tool_driver.Telnet_disconnect;
                end
                if isa(app.MC2_cycle_update_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.MC2_cycle_update_maintenance_tool_driver.Telnet_disconnect;
                end
            end

            % 清除刷新使能
            app.MC1_trajectory_refresh_enable = false;
            app.MC2_trajectory_refresh_enable = false;
            app.MC1_A0_offset_refresh_enable = false;
            app.MC2_A0_offset_refresh_enable = false;
            app.MC1_A1_offset_refresh_enable = false;
            app.MC2_A1_offset_refresh_enable = false;
            app.MC1_trajectory_index_refresh_enable = false;
            app.MC2_trajectory_index_refresh_enable = false;
            app.MC1_yaw_angle_offset_refresh_enable = false;
            app.MC2_yaw_angle_offset_refresh_enable = false;

            % 恢复控件默认属性
            app.Button_disconnect.Enable = false;
            app.Button_connect.Enable = true;
            app.Button_connect.Value = false;
            app.Button_reboot.Enable = false;
            app.Label_MC1_connection_status.Text = 'MC1已断开';
            app.Label_MC1_connection_status.BackgroundColor = [0.65, 0.65, 0.65]; % Gray
            app.Label_MC2_connection_status.Text = 'MC2已断开';
            app.Label_MC2_connection_status.BackgroundColor = [0.65, 0.65, 0.65]; % Gray
            app.EditField_MC1_steering_wheel_angle_compensation.Value = 0;
            app.EditField_MC1_steering_wheel_angle_compensation.BackgroundColor = [1, 1, 1]; % White
            app.EditField_MC2_steering_wheel_angle_compensation.Value = 0;
            app.EditField_MC2_steering_wheel_angle_compensation.BackgroundColor = [1, 1, 1]; % White
            app.EditField_MC1_steering_axle_angle_compensation.Value = 0;
            app.EditField_MC1_steering_axle_angle_compensation.BackgroundColor = [1, 1, 1]; % White
            app.EditField_MC2_steering_axle_angle_compensation.Value = 0;
            app.EditField_MC2_steering_axle_angle_compensation.BackgroundColor = [1, 1, 1]; % White
            app.Button_save_steering_wheel_angle_compensation.Enable = false;
            app.Button_save_A1_offset.Enable = false;
            app.Button_save_A0_offset.Enable = false;
            app.Button_factory_refresh.Enable = false;
            app.Button_after_sale_update.Enable = false;
            app.Button_save_yaw_angle_offset.Enable = false;
            if true == app.MC1_debug_part_enable
                app.EditField_MC1_A1_offset.Value = 0;
                app.EditField_MC1_A1_offset.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC1_A0_offset.Value = 0;
                app.EditField_MC1_A0_offset.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC1_steering_wheel_angle.Value = 0;
                app.EditField_MC1_steering_wheel_angle.BackgroundColor = [1, 1, 1]; % White
                app.Button_MC1_steering_wheel_angle_set_zero.Enable = false;
                app.Button_MC1_steering_wheel_angle_set_zero_old.Enable = false;
                app.Button_MC1_A1_shift_left.Enable = false;
                app.Button_MC1_A1_shift_right.Enable = false;
                app.Button_MC1_A0_shift_left.Enable = false;
                app.Button_MC1_A0_shift_right.Enable = false;
                app.EditField_MC1_task_index.Value = 0;
                app.EditField_MC1_task_index.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC1_task_index.Editable = false;
                app.Button_MC1_reference_trajectory_refresh.Enable = false;
                app.EditField_MC1_grid_origin_latitude.Value = 0;
                app.EditField_MC1_grid_origin_latitude.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC1_grid_origin_latitude.Editable = false;
                app.EditField_MC1_grid_origin_longitude.Value = 0;
                app.EditField_MC1_grid_origin_longitude.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC1_grid_origin_longitude.Editable = false;
                app.EditField_MC1_reference_trajectory_index.Value = 0;
                app.EditField_MC1_reference_trajectory_index.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC1_reference_trajectory_index.Editable = false;
                app.EditField_MC1_yaw_angle_offset.Value = 0;
                app.EditField_MC1_yaw_angle_offset.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC1_yaw_angle_offset.Editable = false;
            end
            if true == app.MC2_debug_part_enable
                app.EditField_MC2_A1_offset.Value = 0;
                app.EditField_MC2_A1_offset.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC2_A0_offset.Value = 0;
                app.EditField_MC2_A0_offset.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC2_steering_wheel_angle.Value = 0;
                app.EditField_MC2_steering_wheel_angle.BackgroundColor = [1, 1, 1]; % White
                app.Button_MC2_steering_wheel_angle_set_zero.Enable = false;
                app.Button_MC2_steering_wheel_angle_set_zero_old.Enable = false;
                app.Button_MC2_A1_shift_left.Enable = false;
                app.Button_MC2_A1_shift_right.Enable = false;
                app.Button_MC2_A0_shift_left.Enable = false;
                app.Button_MC2_A0_shift_right.Enable = false;
                app.EditField_MC2_task_index.Value = 0;
                app.EditField_MC2_task_index.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC2_task_index.Editable = false;
                app.Button_MC2_reference_trajectory_refresh.Enable = false;
                app.EditField_MC2_grid_origin_latitude.Value = 0;
                app.EditField_MC2_grid_origin_latitude.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC2_grid_origin_latitude.Editable = false;
                app.EditField_MC2_grid_origin_longitude.Value = 0;
                app.EditField_MC2_grid_origin_longitude.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC2_grid_origin_longitude.Editable = false;
                app.EditField_MC2_reference_trajectory_index.Value = 0;
                app.EditField_MC2_reference_trajectory_index.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC2_reference_trajectory_index.Editable = false;
                app.EditField_MC2_yaw_angle_offset.Value = 0;
                app.EditField_MC2_yaw_angle_offset.BackgroundColor = [1, 1, 1]; % White
                app.EditField_MC2_yaw_angle_offset.Editable = false;
            end
        end

        function [] = Update_steering_wheel_angle(app)
            if true == app.MC1_debug_part_enable
                app.EditField_MC1_steering_wheel_angle.Value = app.MC1_cycle_update_maintenance_tool_driver.Get_original_steering_wheel_angle;
            end
            if true == app.MC2_debug_part_enable
                app.EditField_MC2_steering_wheel_angle.Value = app.MC2_cycle_update_maintenance_tool_driver.Get_original_steering_wheel_angle;
            end
        end


        function [] = Factory_refresh_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将制造出厂按钮、售后维护按钮锁定，防止重复刷新软件
                app.Button_factory_refresh.Enable = false;
                app.Button_after_sale_update.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新ADAS软件过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：文件解压缩
                progress_dialog.Value = .33;
                progress_dialog.Message = '文件解压缩';
                pause(1)

                % 将目标码文件解压缩到UpdateFiles文件夹中
                unzip(app.ADAS_software_zip_path, [getenv('temp'), '\UpdateFiles']);

                % 进度条对话框：上传ADAS文件
                progress_dialog.Value = .67;
                progress_dialog.Message = '上传ADAS文件';

                % 根据所连在的车头刷新ADAS软件
                if true == app.MC1_debug_part_enable
                    app.MC1_maintenance_tool_driver.ADAS_software_refresh;
                    app.MC1_maintenance_tool_driver.Set_embeded_system_time;
                end
                if true == app.MC2_debug_part_enable
                    app.MC2_maintenance_tool_driver.ADAS_software_refresh;
                    app.MC2_maintenance_tool_driver.Set_embeded_system_time;
                end

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放此前锁定的按钮
                pause(2)
                app.Button_reboot.Enable = true;
                app.Button_factory_refresh.Enable = true;
                app.Button_after_sale_update.Enable = true;
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, 'ADAS软件更新取消！', '警告', 'Icon', 'warning');
            end
        end

        function [] = After_sale_update_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将制造出厂按钮、售后维护按钮锁定，防止重复刷新软件
                app.Button_factory_refresh.Enable = false;
                app.Button_after_sale_update.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新ADAS软件过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：文件解压缩
                progress_dialog.Value = .2;
                progress_dialog.Message = '文件解压缩';
                pause(1)

                % 将目标码文件解压缩到UpdateFiles文件夹中
                unzip(app.ADAS_software_zip_path, [getenv('temp'), '\UpdateFiles']);

                % 进度条对话框：获取原有配置参数
                progress_dialog.Value = .4;
                progress_dialog.Message = '获取原有配置参数';
                pause(1)

                % 进度条对话框：将配置参数更新至新的配置文件中
                progress_dialog.Value = .6;
                progress_dialog.Message = '将配置参数更新至新的配置文件中';
                pause(1)

                % 进度条对话框：上传ADAS文件
                progress_dialog.Value = .8;
                progress_dialog.Message = '上传ADAS文件';
                pause(1)

                % 根据所连接的车头获取控制器内原有ADAS软件，读取原有配置参数并更新到新的配置文件中，最后刷新控制器中的ADAS软件
                if true == app.MC1_debug_part_enable
                    app.MC1_maintenance_tool_driver.Get_previous_ADAS_files;
                    if 214 > app.MC1_software_version_number % 软件版本号数值小于214的是老风格配置文件
                        key_parameter = app.MC1_maintenance_tool_driver.Read_old_style_ADAS_config_file_key_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC1\ADASCFG.xml']);
                    else
                        key_parameter = app.MC1_maintenance_tool_driver.Read_new_style_ADAS_config_file_key_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC1\ADASCFG.xml']);
                    end
                    app.MC1_maintenance_tool_driver.Update_new_ADAS_config_file([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], key_parameter);
                    temp_reference_trajectory_index = app.MC1_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC1\MMUCFG.xml'], 'project_config', 'reference_trajectory_index');
                    app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\MMUCFG.xml'], 'reference_trajectory_index', round(temp_reference_trajectory_index));
                    temp_yaw_angle_offset = app.MC1_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC1\ADASCFG.xml'], 'control_parameter_config', 'yaw_angle_offset');
                    app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'yaw_angle_offset', temp_yaw_angle_offset);
                    temp_steering_axle_angle_offset = app.MC1_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC1\ADASCFG.xml'], 'control_parameter_config', 'steering_axle_angle_offset');
                    app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'steering_axle_angle_offset', temp_steering_axle_angle_offset);
                    app.MC1_maintenance_tool_driver.ADAS_software_refresh;
                    app.MC1_maintenance_tool_driver.Set_embeded_system_time;
                end
                if true == app.MC2_debug_part_enable
                    app.MC2_maintenance_tool_driver.Get_previous_ADAS_files;
                    if 214 > app.MC2_software_version_number % 软件版本号数值小于214的是老风格配置文件
                        key_parameter = app.MC2_maintenance_tool_driver.Read_old_style_ADAS_config_file_key_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC2\ADASCFG.xml']);
                    else
                        key_parameter = app.MC2_maintenance_tool_driver.Read_new_style_ADAS_config_file_key_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC2\ADASCFG.xml']);
                    end
                    app.MC2_maintenance_tool_driver.Update_new_ADAS_config_file([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], key_parameter);
                    temp_reference_trajectory_index = app.MC2_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC2\MMUCFG.xml'], 'project_config', 'reference_trajectory_index');
                    app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\MMUCFG.xml'], 'reference_trajectory_index', round(temp_reference_trajectory_index));
                    temp_yaw_angle_offset = app.MC2_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC2\ADASCFG.xml'], 'control_parameter_config', 'yaw_angle_offset');
                    app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'yaw_angle_offset', temp_yaw_angle_offset);
                    temp_steering_axle_angle_offset = app.MC2_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\PreviousFilesBackup\MC2\ADASCFG.xml'], 'control_parameter_config', 'steering_axle_angle_offset');
                    app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'steering_axle_angle_offset', temp_steering_axle_angle_offset);
                    app.MC2_maintenance_tool_driver.ADAS_software_refresh;
                    app.MC2_maintenance_tool_driver.Set_embeded_system_time;
                end

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放此前锁定的按钮
                pause(2);
                app.Button_reboot.Enable = true;
                app.Button_factory_refresh.Enable = true;
                app.Button_after_sale_update.Enable = true;
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, 'ADAS软件更新取消！', '警告', 'Icon', 'warning');
            end
        end

        function [] = Save_steering_wheel_angle_compensation_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将保存方向盘软件零位标定参数按钮锁定，防止重复保存出现数值不断叠加的问题
                app.Button_save_steering_wheel_angle_compensation.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新ADAS配置参数过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：下载原有配置文件
                progress_dialog.Value = .25;
                progress_dialog.Message = '下载原有配置文件';
                pause(1)

                % 进度条对话框：方向盘软件零位标定参数叠加补偿值以后再更新至配置文件中
                progress_dialog.Value = .5;
                progress_dialog.Message = '方向盘软件零位标定参数叠加补偿值以后再更新至配置文件中';
                pause(1)

                % 进度条对话框：上传配置文件
                progress_dialog.Value = .75;
                progress_dialog.Message = '上传配置文件';
                pause(1)

                % 在方向盘补偿值绝对值≥5，或者转向轴补偿值绝对值≥0.3时，根据所连接的车头获取控制器内原有ADASCFG，读取原有配置参数，补偿以后再更新到配置文件中，最后将文件更新到控制器中
                if (true == app.MC1_debug_part_enable ) && ...
                        ((5 <= abs(app.EditField_MC1_steering_wheel_angle_compensation.Value)) || ...
                         (0.3 <= abs(app.EditField_MC1_steering_axle_angle_compensation.Value)))
                    app.MC1_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC1\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\']);
                    if 5 <= abs(app.EditField_MC1_steering_wheel_angle_compensation.Value)
                        steering_wheel_angle_offset = app.MC1_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'control_parameter_config', 'steering_wheel_angle_offset');
                        new_steering_wheel_angle_offset = steering_wheel_angle_offset + app.EditField_MC1_steering_wheel_angle_compensation.Value;
                        app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'steering_wheel_angle_offset', new_steering_wheel_angle_offset);
                    end
                    if 0.3 <= abs(app.EditField_MC1_steering_axle_angle_compensation.Value)
                        steering_axle_angle_offset = app.MC1_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'control_parameter_config', 'steering_axle_angle_offset');
                        new_steering_axle_angle_offset = steering_axle_angle_offset + app.EditField_MC1_steering_axle_angle_compensation.Value;
                        app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'steering_axle_angle_offset', new_steering_axle_angle_offset);
                    end
                    app.MC1_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');
                end
                if (true == app.MC2_debug_part_enable) && ...
                        ((5 <= abs(app.EditField_MC2_steering_wheel_angle_compensation.Value)) || ...
                         (0.3 <= abs(app.EditField_MC2_steering_axle_angle_compensation.Value)))
                    app.MC2_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC2\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\']);
                    if 5 <= abs(app.EditField_MC2_steering_wheel_angle_compensation.Value)
                        steering_wheel_angle_offset = app.MC2_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'control_parameter_config', 'steering_wheel_angle_offset');
                        new_steering_wheel_angle_offset = steering_wheel_angle_offset + app.EditField_MC2_steering_wheel_angle_compensation.Value;
                        app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'steering_wheel_angle_offset', new_steering_wheel_angle_offset);
                    end
                    if 0.3 <= abs(app.EditField_MC2_steering_axle_angle_compensation.Value)
                        steering_axle_angle_offset = app.MC2_maintenance_tool_driver.Get_XML_config_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'control_parameter_config', 'steering_axle_angle_offset');
                        new_steering_axle_angle_offset = steering_axle_angle_offset + app.EditField_MC2_steering_axle_angle_compensation.Value;
                        app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'steering_axle_angle_offset', new_steering_axle_angle_offset);
                    end
                    app.MC2_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');
                end

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放热重启控制器按钮
                pause(2);
                app.Button_reboot.Enable = true;
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, '参数写入控制器取消！', '警告', 'Icon', 'warning');
            end
        end

        function [] = Save_A1_offset_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将保存循迹摄像头航向标定参数按钮锁定，防止重复保存
                app.Button_save_A1_offset.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新ADAS配置参数过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：下载原有配置文件
                progress_dialog.Value = .25;
                progress_dialog.Message = '下载原有配置文件';
                pause(1)

                % 进度条对话框：循迹摄像头航向标定参数更新至配置文件中
                progress_dialog.Value = .5;
                progress_dialog.Message = '循迹摄像头航向标定参数更新至配置文件中';
                pause(1)

                % 进度条对话框：上传配置文件
                progress_dialog.Value = .75;
                progress_dialog.Message = '上传配置文件';
                pause(1)

                % 在A1_offset被修改过以后，根据所连接的车头获取控制器内原有ADASCFG，将新设置的A1_offset写入配置文件中，最后将文件更新到控制器中
                if true == app.MC1_debug_part_enable && ...
                        true == app.MC1_A1_offset_refresh_enable
                    app.MC1_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC1\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\']);
                    app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'A1_offset', app.EditField_MC1_A1_offset.Value);
                    app.MC1_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');
                    app.MC1_A1_offset_refresh_enable = false;
                end
                if true == app.MC2_debug_part_enable && ...
                        true == app.MC2_A1_offset_refresh_enable
                    app.MC2_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC2\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\']);
                    app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'A1_offset', app.EditField_MC2_A1_offset.Value);
                    app.MC2_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');
                    app.MC2_A1_offset_refresh_enable = false;
                end

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放此前锁定的按钮
                pause(2);
                app.Button_reboot.Enable = true;
                app.Button_save_A1_offset.Enable = true;
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, '参数写入控制器取消！', '警告', 'Icon', 'warning');
            end
        end

        function [] = Save_A0_offset_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将保存循迹摄像头轨迹偏差标定参数按钮锁定，防止重复保存
                app.Button_save_A0_offset.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新ADAS配置参数过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：下载原有配置文件
                progress_dialog.Value = .25;
                progress_dialog.Message = '下载原有配置文件';
                pause(1)

                % 进度条对话框：循迹摄像头轨迹偏差标定参数更新至配置文件中
                progress_dialog.Value = .5;
                progress_dialog.Message = '循迹摄像头轨迹偏差标定参数更新至配置文件中';
                pause(1)

                % 进度条对话框：上传配置文件
                progress_dialog.Value = .75;
                progress_dialog.Message = '上传配置文件';
                pause(1)

                % 在A0_offset被修改过以后，根据所连接的车头获取控制器内原有ADASCFG，将新设置的A0_offset写入配置文件中，最后将文件更新到控制器中
                if true == app.MC1_debug_part_enable && ...
                        true == app.MC1_A0_offset_refresh_enable
                    app.MC1_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC1\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\']);
                    app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'A0_offset', app.EditField_MC1_A0_offset.Value);
                    app.MC1_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');
                    app.MC1_A0_offset_refresh_enable = false;
                end
                if true == app.MC2_debug_part_enable && ...
                        true == app.MC2_A0_offset_refresh_enable
                    app.MC2_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC2\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\']);
                    app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'A0_offset', app.EditField_MC2_A0_offset.Value);
                    app.MC2_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');
                    app.MC2_A0_offset_refresh_enable = false;
                end

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放此前锁定的按钮
                pause(2);
                app.Button_reboot.Enable = true;
                app.Button_save_A0_offset.Enable = true;
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, '参数写入控制器取消！', '警告', 'Icon', 'warning');
            end
        end

        function [] = Save_YongXiu_MC1_A0_offset_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将保存循迹摄像头轨迹偏差标定参数按钮锁定，防止重复保存
                app.Button_save_YongXiu_MC1_A0_offset.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新ADAS配置参数过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：下载原有配置文件
                progress_dialog.Value = .25;
                progress_dialog.Message = '下载原有配置文件';
                pause(1)

                % 进度条对话框：循迹摄像头轨迹偏差标定参数更新至配置文件中
                progress_dialog.Value = .5;
                progress_dialog.Message = '循迹摄像头轨迹偏差标定参数更新至配置文件中';
                pause(1)

                % 进度条对话框：上传配置文件
                progress_dialog.Value = .75;
                progress_dialog.Message = '上传配置文件';
                pause(1)

                % 在参数被修改过以后，获取控制器内原有ADASCFG，将新设置的参数写入配置文件中，最后将文件更新到控制器中
                app.MC1_maintenance_tool_driver.Get_previous_ADAS_files;
                copyfile([getenv('temp'), '\PreviousFilesBackup\MC1\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\']);
                app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'A0_offset', app.EditField_MC1_A0_s1_offset.Value);
                app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'A0_offset_step2', app.EditField_MC1_A0_s2_offset.Value);
                app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'steering_wheel_angle_threshold', app.EditField_MC1_A0_switch_threshold.Value);
                app.MC1_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放此前锁定的按钮
                pause(2);
                app.Button_reboot.Enable = true;
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, '参数写入控制器取消！', '警告', 'Icon', 'warning');
            end
        end

        function [] = Save_YongXiu_MC2_A0_offset_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将保存循迹摄像头轨迹偏差标定参数按钮锁定，防止重复保存
                app.Button_save_YongXiu_MC2_A0_offset.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新ADAS配置参数过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：下载原有配置文件
                progress_dialog.Value = .25;
                progress_dialog.Message = '下载原有配置文件';
                pause(1)

                % 进度条对话框：循迹摄像头轨迹偏差标定参数更新至配置文件中
                progress_dialog.Value = .5;
                progress_dialog.Message = '循迹摄像头轨迹偏差标定参数更新至配置文件中';
                pause(1)

                % 进度条对话框：上传配置文件
                progress_dialog.Value = .75;
                progress_dialog.Message = '上传配置文件';
                pause(1)

                % 在A0_offset被修改过以后，获取控制器内原有ADASCFG，将新设置的A0_offset写入配置文件中，最后将文件更新到控制器中
                app.MC2_maintenance_tool_driver.Get_previous_ADAS_files;
                copyfile([getenv('temp'), '\PreviousFilesBackup\MC2\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\']);
                app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'A0_offset', app.EditField_MC2_A0_s1_offset.Value);
                app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'A0_offset_step2', app.EditField_MC2_A0_s2_offset.Value);
                app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'steering_wheel_angle_threshold', app.EditField_MC2_A0_switch_threshold.Value);
                app.MC2_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放此前锁定的按钮
                pause(2);
                app.Button_reboot.Enable = true;
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, '参数写入控制器取消！', '警告', 'Icon', 'warning');
            end
        end

        function [] = Reference_trajectory_refresh_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将轨迹文件写入控制器按钮锁定，防止重复刷新轨迹
                app.Button_MC1_reference_trajectory_refresh.Enable = false;
                app.Button_MC2_reference_trajectory_refresh.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新参考轨迹过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：文件解压缩
                progress_dialog.Value = .33;
                progress_dialog.Message = '文件解压缩';
                pause(1)

                % 将轨迹文件解压缩到UpdateFiles文件夹中
                filenames = unzip(app.reference_trajectory_zip_path, [getenv('temp'), '\UpdateFiles\Reference_trajectories']);

                % 进度条对话框：上传参考轨迹
                progress_dialog.Value = .67;
                progress_dialog.Message = '上传参考轨迹';

                % 根据轨迹刷新使能状态选择刷新参考轨迹
                if true == app.MC1_trajectory_refresh_enable

                    % 循环刷新解压后的每一个轨迹文件
                    if ~isempty(filenames)
                        for index = 1 : length(filenames)
                            app.MC1_maintenance_tool_driver.Reference_trajectory_refresh(filenames{index});
                            pause(2);
                        end
                    end

                    % 刷新过后取消本端的轨迹刷新使能，防止重复刷新
                    app.MC1_trajectory_refresh_enable = false;

                end
                if true == app.MC2_trajectory_refresh_enable

                    % 循环刷新解压后的每一个轨迹文件
                    if ~isempty(filenames)
                        for index = 1 : length(filenames)
                            app.MC2_maintenance_tool_driver.Reference_trajectory_refresh(filenames{index});
                            pause(2);
                        end
                    end

                    % 刷新过后取消本端的轨迹刷新使能，防止重复刷新
                    app.MC2_trajectory_refresh_enable = false;

                end

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放此前锁定的按钮
                pause(2)
                app.Button_reboot.Enable = true;
                if true == app.MC1_debug_part_enable
                    app.Button_MC1_reference_trajectory_refresh.Enable = true;
                end
                if true == app.MC2_debug_part_enable
                    app.Button_MC2_reference_trajectory_refresh.Enable = true;
                end
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, '参考轨迹更新取消！', '警告', 'Icon', 'warning');
            end
        end
        
        function [] = Save_reference_trajectory_index_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将保存参考轨迹代号参数按钮锁定，防止重复保存
                app.Button_save_reference_trajectory_index.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新参考轨迹代号参数过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：下载原有配置文件
                progress_dialog.Value = .25;
                progress_dialog.Message = '下载原有配置文件';
                pause(1)

                % 进度条对话框：参考轨迹代号参数更新至配置文件中
                progress_dialog.Value = .5;
                progress_dialog.Message = '参考轨迹代号参数更新至配置文件中';
                pause(1)

                % 进度条对话框：上传配置文件
                progress_dialog.Value = .75;
                progress_dialog.Message = '上传配置文件';
                pause(1)

                % 在参考轨迹代号被修改过以后，根据所连接的车头获取控制器内原有MMUCFG，将新设置的reference_trajectory_index写入配置文件中，最后将文件更新到控制器中
                if true == app.MC1_debug_part_enable && ...
                        true == app.MC1_trajectory_index_refresh_enable
                    app.MC1_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC1\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\']);
                    app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\MMUCFG.xml'], 'reference_trajectory_index', app.EditField_MC1_reference_trajectory_index.Value);
                    app.MC1_maintenance_tool_driver.ADAS_config_file_refresh('MMUCFG.xml');
                    app.MC1_trajectory_index_refresh_enable = false;
                end
                if true == app.MC2_debug_part_enable && ...
                        true == app.MC2_trajectory_index_refresh_enable
                    app.MC2_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC2\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\']);
                    app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\MMUCFG.xml'], 'reference_trajectory_index', app.EditField_MC2_reference_trajectory_index.Value);
                    app.MC2_maintenance_tool_driver.ADAS_config_file_refresh('MMUCFG.xml');
                    app.MC2_trajectory_index_refresh_enable = false;
                end

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放此前锁定的按钮
                pause(2);
                app.Button_reboot.Enable = true;
                app.Button_save_reference_trajectory_index.Enable = true;
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, '参数写入控制器取消！', '警告', 'Icon', 'warning');
            end
        end

        function [] = Save_yaw_angle_offset_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex % 确认对话框里选择第一个按钮（确定按钮）
                % 将热重启控制器按钮锁定，防止误重启
                app.Button_reboot.Enable = false;

                % 将保存组合惯导横摆标定参数按钮锁定，防止重复保存
                app.Button_save_yaw_angle_offset.Enable = false;

                % 创建进度条对话框
                progress_dialog = uiprogressdlg(app.V14UIFigure, 'Title', '刷新ADAS配置参数过程中...请勿操作电脑，切忌断电断网！', 'Message', '初始化', 'Icon', 'warning');
                pause(.5)

                % 进度条对话框：下载原有配置文件
                progress_dialog.Value = .25;
                progress_dialog.Message = '下载原有配置文件';
                pause(1)

                % 进度条对话框：组合惯导横摆标定参数更新至配置文件中
                progress_dialog.Value = .5;
                progress_dialog.Message = '组合惯导横摆标定参数更新至配置文件中';
                pause(1)

                % 进度条对话框：上传配置文件
                progress_dialog.Value = .75;
                progress_dialog.Message = '上传配置文件';
                pause(1)

                % 在yaw_angle_offset被修改过以后，根据所连接的车头获取控制器内原有ADASCFG，将新设置的yaw_angle_offset写入配置文件中，最后将文件更新到控制器中
                if true == app.MC1_debug_part_enable && ...
                        true == app.MC1_yaw_angle_offset_refresh_enable
                    app.MC1_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC1\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\']);
                    app.MC1_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC1_maintenance_tool_driver.tram_type_symbols{app.MC1_maintenance_tool_driver.tram_type_index + 1}), '\MC1\ADASCFG.xml'], 'yaw_angle_offset', app.EditField_MC1_yaw_angle_offset.Value);
                    app.MC1_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');
                    app.MC1_yaw_angle_offset_refresh_enable = false;
                end
                if true == app.MC2_debug_part_enable && ...
                        true == app.MC2_yaw_angle_offset_refresh_enable
                    app.MC2_maintenance_tool_driver.Get_previous_ADAS_files;
                    copyfile([getenv('temp'), '\PreviousFilesBackup\MC2\'], [getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\']);
                    app.MC2_maintenance_tool_driver.Update_XML_parameter_value([getenv('temp'), '\UpdateFiles\01-PU300\', char(app.MC2_maintenance_tool_driver.tram_type_symbols{app.MC2_maintenance_tool_driver.tram_type_index + 1}), '\MC2\ADASCFG.xml'], 'yaw_angle_offset', app.EditField_MC2_yaw_angle_offset.Value);
                    app.MC2_maintenance_tool_driver.ADAS_config_file_refresh('ADASCFG.xml');
                    app.MC2_yaw_angle_offset_refresh_enable = false;
                end

                % 进度条对话框：文件传输完毕
                progress_dialog.Value = 1;
                progress_dialog.Message = '文件传输完毕';
                progress_dialog.Icon = 'success';
                pause(1)

                % 关闭进度条对话框
                close(progress_dialog);

                % 延时2s，待控制器内文件保存完整后再释放此前锁定的按钮
                pause(2);
                app.Button_reboot.Enable = true;
                app.Button_save_yaw_angle_offset.Enable = true;
            elseif 2 == event.SelectedOptionIndex % 确认对话框里选择第二个按钮（取消按钮）
                uialert(app.V14UIFigure, '参数写入控制器取消！', '警告', 'Icon', 'warning');
            end
        end
    end

    methods (Access = public)

        function [] = Connect_to_target(app, dialog_app, tram_type_list, tram_type_symbols, tram_type_index, net_index, cab_index)
            % 关闭对话框
            delete(dialog_app)

            % 更新车型
            app.MC1_maintenance_tool_driver.tram_type_list = tram_type_list;
            app.MC2_maintenance_tool_driver.tram_type_list = tram_type_list;
            app.MC1_cycle_update_maintenance_tool_driver.tram_type_list = tram_type_list;
            app.MC2_cycle_update_maintenance_tool_driver.tram_type_list = tram_type_list;
            app.MC1_maintenance_tool_driver.tram_type_symbols = tram_type_symbols;
            app.MC2_maintenance_tool_driver.tram_type_symbols = tram_type_symbols;
            app.MC1_cycle_update_maintenance_tool_driver.tram_type_symbols = tram_type_symbols;
            app.MC2_cycle_update_maintenance_tool_driver.tram_type_symbols = tram_type_symbols;
            app.MC1_maintenance_tool_driver.tram_type_index = tram_type_index;
            app.MC2_maintenance_tool_driver.tram_type_index = tram_type_index;
            app.MC1_cycle_update_maintenance_tool_driver.tram_type_index = tram_type_index;
            app.MC2_cycle_update_maintenance_tool_driver.tram_type_index = tram_type_index;
            if 0 == tram_type_index
                uialert(app.V14UIFigure, '未定义车型.', '错误提示');
            end

            % 更新网口信息
            if 1 == net_index
                app.MC1_debug_part_enable = true;
                app.MC2_debug_part_enable = true;

                % 区分一代、二代平台，IP不同
                if (5 > tram_type_index) || ...
                        (7 == tram_type_index)
                    app.MC1_maintenance_tool_driver.target_IP = '10.0.1.101';
                    app.MC2_maintenance_tool_driver.target_IP = '10.0.3.101';
                    app.MC1_cycle_update_maintenance_tool_driver.target_IP = '10.0.1.101';
                    app.MC2_cycle_update_maintenance_tool_driver.target_IP = '10.0.3.101';
                else
                    app.MC1_maintenance_tool_driver.target_IP = '10.0.1.180';
                    app.MC2_maintenance_tool_driver.target_IP = '10.0.3.180';
                    app.MC1_cycle_update_maintenance_tool_driver.target_IP = '10.0.1.180';
                    app.MC2_cycle_update_maintenance_tool_driver.target_IP = '10.0.3.180';
                end
            elseif 2 == net_index
                app.MC1_maintenance_tool_driver.target_IP = '169.254.147.106';
                app.MC2_maintenance_tool_driver.target_IP = '169.254.147.106';
                app.MC1_cycle_update_maintenance_tool_driver.target_IP = '169.254.147.106';
                app.MC2_cycle_update_maintenance_tool_driver.target_IP = '169.254.147.106';
                if 1 == cab_index
                    app.MC1_debug_part_enable = true;
                    app.MC2_debug_part_enable = false;
                elseif 2 == cab_index
                    app.MC1_debug_part_enable = false;
                    app.MC2_debug_part_enable = true;
                end
            end

            % 版本号文字初始化
            MC1_lateral_control_software_version = '0.0.0';
            MC2_lateral_control_software_version = '0.0.0';

            % 连接目标控制器
            if true == app.MC1_debug_part_enable
                app.MC1_maintenance_tool_driver = app.MC1_maintenance_tool_driver.Telnet_connect;
                app.MC1_cycle_update_maintenance_tool_driver = app.MC1_cycle_update_maintenance_tool_driver.Telnet_connect;
                if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.Label_MC1_connection_status.Text = 'MC1已连接';
                    app.Label_MC1_connection_status.BackgroundColor = [0, 1, 0]; % Green
                    MC1_lateral_control_software_version = app.MC1_maintenance_tool_driver.Get_lateral_control_software_version;
                    app.Label_MC1_lateral_control_software_version.Text = sprintf('MC1循迹控制软件版本：%s', MC1_lateral_control_software_version);
                    MC1_software_version_matrix = regexp(MC1_lateral_control_software_version, '\d+', 'match');
                    app.MC1_software_version_number = str2double(cell2mat(MC1_software_version_matrix));
                    app.original_MC1_A1_offset = app.MC1_maintenance_tool_driver.Get_A1_offset;
                    app.EditField_MC1_A1_offset.Value = app.original_MC1_A1_offset;
                    app.EditField_MC1_A1_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.original_MC1_A0_offset = app.MC1_maintenance_tool_driver.Get_A0_offset;
                    app.EditField_MC1_A0_offset.Value = app.original_MC1_A0_offset;
                    app.EditField_MC1_A0_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC1_steering_wheel_angle.BackgroundColor = [0, 1, 0]; % Green
                    app.Button_MC1_steering_wheel_angle_set_zero.Enable = true;
                    app.Button_MC1_steering_wheel_angle_set_zero_old.Enable = true;
                    app.Button_MC1_A1_shift_left.Enable = true;
                    app.Button_MC1_A1_shift_right.Enable = true;
                    app.Button_MC1_A0_shift_left.Enable = true;
                    app.Button_MC1_A0_shift_right.Enable = true;
                    app.original_MC1_A0_s1_offset = app.MC1_maintenance_tool_driver.Get_A0_s1_offset;
                    app.EditField_MC1_A0_s1_offset.Value = app.original_MC1_A0_s1_offset;
                    app.EditField_MC1_A0_s1_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.original_MC1_A0_s2_offset = app.MC1_maintenance_tool_driver.Get_A0_s2_offset;
                    app.EditField_MC1_A0_s2_offset.Value = app.original_MC1_A0_s2_offset;
                    app.EditField_MC1_A0_s2_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.Button_MC1_A0_s1_shift_left.Enable = true;
                    app.Button_MC1_A0_s1_shift_right.Enable = true;
                    app.Button_MC1_A0_s2_shift_left.Enable = true;
                    app.Button_MC1_A0_s2_shift_right.Enable = true;
                    app.original_MC1_A0_switch_threshold = app.MC1_maintenance_tool_driver.Get_A0_switch_threshold;
                    app.EditField_MC1_A0_switch_threshold.Value = app.original_MC1_A0_switch_threshold;
                    app.EditField_MC1_A0_switch_threshold.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC1_task_index.Value = app.MC1_maintenance_tool_driver.Get_task_index;
                    app.EditField_MC1_task_index.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC1_task_index.Editable = true;
                    app.Button_MC1_reference_trajectory_refresh.Enable = true;
                    app.EditField_MC1_grid_origin_latitude.Value = app.MC1_maintenance_tool_driver.Get_grid_origin_latitude;
                    app.EditField_MC1_grid_origin_latitude.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC1_grid_origin_latitude.Editable = true;
                    app.EditField_MC1_grid_origin_longitude.Value = app.MC1_maintenance_tool_driver.Get_grid_origin_longitude;
                    app.EditField_MC1_grid_origin_longitude.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC1_grid_origin_longitude.Editable = true;
                    app.EditField_MC1_reference_trajectory_index.Value = app.MC1_maintenance_tool_driver.Get_reference_trajectory_index;
                    app.EditField_MC1_reference_trajectory_index.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC1_reference_trajectory_index.Editable = true;
                    app.EditField_MC1_yaw_angle_offset.Value = app.MC1_maintenance_tool_driver.Get_yaw_angle_offset;
                    app.EditField_MC1_yaw_angle_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC1_yaw_angle_offset.Editable = true;
                end
            end
            if true == app.MC2_debug_part_enable
                app.MC2_maintenance_tool_driver = app.MC2_maintenance_tool_driver.Telnet_connect;
                app.MC2_cycle_update_maintenance_tool_driver = app.MC2_cycle_update_maintenance_tool_driver.Telnet_connect;
                if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.Label_MC2_connection_status.Text = 'MC2已连接';
                    app.Label_MC2_connection_status.BackgroundColor = [0, 1, 0]; % Green
                    MC2_lateral_control_software_version = app.MC2_maintenance_tool_driver.Get_lateral_control_software_version;
                    MC2_software_version_matrix = regexp(MC2_lateral_control_software_version, '\d+', 'match');
                    app.MC2_software_version_number = str2double(cell2mat(MC2_software_version_matrix));
                    app.Label_MC2_lateral_control_software_version.Text = sprintf('MC2循迹控制软件版本：%s', MC2_lateral_control_software_version);
                    app.original_MC2_A1_offset = app.MC2_maintenance_tool_driver.Get_A1_offset;
                    app.EditField_MC2_A1_offset.Value = app.original_MC2_A1_offset;
                    app.EditField_MC2_A1_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.original_MC2_A0_offset = app.MC2_maintenance_tool_driver.Get_A0_offset;
                    app.EditField_MC2_A0_offset.Value = app.original_MC2_A0_offset;
                    app.EditField_MC2_A0_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC2_steering_wheel_angle.BackgroundColor = [0, 1, 0]; % Green
                    app.Button_MC2_steering_wheel_angle_set_zero.Enable = true;
                    app.Button_MC2_steering_wheel_angle_set_zero_old.Enable = true;
                    app.Button_MC2_A1_shift_left.Enable = true;
                    app.Button_MC2_A1_shift_right.Enable = true;
                    app.Button_MC2_A0_shift_left.Enable = true;
                    app.Button_MC2_A0_shift_right.Enable = true;
                    app.original_MC2_A0_s1_offset = app.MC2_maintenance_tool_driver.Get_A0_s1_offset;
                    app.EditField_MC2_A0_s1_offset.Value = app.original_MC2_A0_s1_offset;
                    app.EditField_MC2_A0_s1_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.original_MC2_A0_s2_offset = app.MC2_maintenance_tool_driver.Get_A0_s2_offset;
                    app.EditField_MC2_A0_s2_offset.Value = app.original_MC2_A0_s2_offset;
                    app.EditField_MC2_A0_s2_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.Button_MC2_A0_s1_shift_left.Enable = true;
                    app.Button_MC2_A0_s1_shift_right.Enable = true;
                    app.Button_MC2_A0_s2_shift_left.Enable = true;
                    app.Button_MC2_A0_s2_shift_right.Enable = true;
                    app.original_MC2_A0_switch_threshold = app.MC2_maintenance_tool_driver.Get_A0_switch_threshold;
                    app.EditField_MC2_A0_switch_threshold.Value = app.original_MC2_A0_switch_threshold;
                    app.EditField_MC2_A0_switch_threshold.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC2_task_index.Value = app.MC2_maintenance_tool_driver.Get_task_index;
                    app.EditField_MC2_task_index.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC2_task_index.Editable = true;
                    app.Button_MC2_reference_trajectory_refresh.Enable = true;
                    app.EditField_MC2_grid_origin_latitude.Value = app.MC2_maintenance_tool_driver.Get_grid_origin_latitude;
                    app.EditField_MC2_grid_origin_latitude.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC2_grid_origin_latitude.Editable = true;
                    app.EditField_MC2_grid_origin_longitude.Value = app.MC2_maintenance_tool_driver.Get_grid_origin_longitude;
                    app.EditField_MC2_grid_origin_longitude.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC2_grid_origin_longitude.Editable = true;
                    app.EditField_MC2_reference_trajectory_index.Value = app.MC2_maintenance_tool_driver.Get_reference_trajectory_index;
                    app.EditField_MC2_reference_trajectory_index.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC2_reference_trajectory_index.Editable = true;
                    app.EditField_MC2_yaw_angle_offset.Value = app.MC2_maintenance_tool_driver.Get_yaw_angle_offset;
                    app.EditField_MC2_yaw_angle_offset.BackgroundColor = [0, 1, 0]; % Green
                    app.EditField_MC2_yaw_angle_offset.Editable = true;
                end
            end
            if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient') || ...
                    isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                app.Button_connect.Enable = false;
                app.Button_disconnect.Enable = true;
                app.Button_disconnect.Value = false;
                app.Button_reboot.Enable = true;
                app.Button_factory_refresh.Enable = true;
                app.Button_after_sale_update.Enable = true;
                app.Label_tram_type.Text = sprintf('%s', tram_type_list{tram_type_index + 1});
                app.Label_tram_type.BackgroundColor = [0.07, 0.62, 1.00]; % Blue
            else
                app.Button_connect.Enable = true;
                app.Button_connect.Value = false;
                app.Label_tram_type.Text = '未选择车型';
                app.Label_tram_type.BackgroundColor = [1, 1, 1]; % White
            end

            % 弹窗提示当前循迹控制软件版本
            software_version_text = sprintf('MC1循迹控制软件版本：%s\nMC2循迹控制软件版本：%s', MC1_lateral_control_software_version, MC2_lateral_control_software_version);
            uialert(app.V14UIFigure, software_version_text, '提示', 'Icon', 'warning');

            % 在Telnet连接对象循环更新通道建立成功后再开启定时更新实时方向盘转角任务
            if isa(app.MC1_cycle_update_maintenance_tool_driver.telnet_object, 'tcpclient') || ...
                    isa(app.MC2_cycle_update_maintenance_tool_driver.telnet_object, 'tcpclient')
                if strcmp(get(app.timer1, 'Running'), 'off')
                    start(app.timer1);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 面向单次连接的MC1端维护工具驱动对象实例初始化
            app.MC1_maintenance_tool_driver = MaintenanceToolDriver(app.V14UIFigure, '10.0.1.101', 1);

            % 面向单次连接的MC2端维护工具驱动对象实例初始化
            app.MC2_maintenance_tool_driver = MaintenanceToolDriver(app.V14UIFigure, '10.0.3.101', 2);

            % 面向循环更新的MC1端维护工具驱动对象实例初始化
            app.MC1_cycle_update_maintenance_tool_driver = MaintenanceToolDriver(app.V14UIFigure, '10.0.1.101', 1);

            % 面向循环更新的MC2端维护工具驱动对象实例初始化
            app.MC2_cycle_update_maintenance_tool_driver = MaintenanceToolDriver(app.V14UIFigure, '10.0.3.101', 2);

            % 创建定时器
            app.timer1 = timer('ExecutionMode', 'fixedSpacing', 'Period', 2, 'TimerFcn', {@(~, ~) app.Update_steering_wheel_angle});
        end

        % Value changed function: Button_connect
        function Button_connectValueChanged(app, event)
            value = app.Button_connect.Value;
            if true == value
                % 锁定主界面
                app.main_figure_lock = true;

                % 打开选择网口对话框
                app.choose_net_port_dialog_app = Choose_net_port_dialog(app);

                % 等待选择网口对话框关闭解锁期间，保持主界面不可点击
                while true
                    if false == app.main_figure_lock
                        break;
                    end
                    pause(0.01);
                end
            end
        end

        % Value changed function: Button_disconnect
        function Button_disconnectValueChanged(app, event)
            value = app.Button_disconnect.Value;
            if true == value
                Disconnect_telnet_connection(app);
            end
        end

        % Button pushed function: Button_reboot
        function Button_rebootPushed(app, event)
            % 发送reboot指令
            if true == app.MC1_debug_part_enable
                app.MC1_maintenance_tool_driver.Telnet_reboot;
            end
            if true == app.MC2_debug_part_enable
                app.MC2_maintenance_tool_driver.Telnet_reboot;
            end
            Disconnect_telnet_connection(app);
        end

        % Button pushed function: Button_MC1_A1_shift_left
        function Button_MC1_A1_shift_leftPushed(app, event)
            app.MC1_maintenance_tool_driver.Set_A1_offset(app.EditField_MC1_A1_offset.Value - 0.001);
            app.EditField_MC1_A1_offset.Value = app.MC1_maintenance_tool_driver.Get_A1_offset;
            app.EditField_MC1_A1_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_A1_offset.Enable = true;
            app.MC1_A1_offset_refresh_enable = true;
        end

        % Button pushed function: Button_MC1_A1_shift_right
        function Button_MC1_A1_shift_rightPushed(app, event)
            app.MC1_maintenance_tool_driver.Set_A1_offset(app.EditField_MC1_A1_offset.Value + 0.001);
            app.EditField_MC1_A1_offset.Value = app.MC1_maintenance_tool_driver.Get_A1_offset;
            app.EditField_MC1_A1_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_A1_offset.Enable = true;
            app.MC1_A1_offset_refresh_enable = true;
        end

        % Button pushed function: Button_MC2_A1_shift_left
        function Button_MC2_A1_shift_leftPushed(app, event)
            app.MC2_maintenance_tool_driver.Set_A1_offset(app.EditField_MC2_A1_offset.Value - 0.001);
            app.EditField_MC2_A1_offset.Value = app.MC2_maintenance_tool_driver.Get_A1_offset;
            app.EditField_MC2_A1_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_A1_offset.Enable = true;
            app.MC2_A1_offset_refresh_enable = true;
        end

        % Button pushed function: Button_MC2_A1_shift_right
        function Button_MC2_A1_shift_rightPushed(app, event)
            app.MC2_maintenance_tool_driver.Set_A1_offset(app.EditField_MC2_A1_offset.Value + 0.001);
            app.EditField_MC2_A1_offset.Value = app.MC2_maintenance_tool_driver.Get_A1_offset;
            app.EditField_MC2_A1_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_A1_offset.Enable = true;
            app.MC2_A1_offset_refresh_enable = true;
        end

        % Button pushed function: 
        % Button_MC1_steering_wheel_angle_set_zero
        function Button_MC1_steering_wheel_angle_set_zeroPushed(app, event)
            app.MC1_maintenance_tool_driver.Steering_wheel_angle_set_zero;
        end

        % Button pushed function: 
        % Button_MC1_steering_wheel_angle_set_zero_old
        function Button_MC1_steering_wheel_angle_set_zero_oldPushed(app, event)
            app.MC1_maintenance_tool_driver.Steering_wheel_angle_set_zero_old;
        end

        % Button pushed function: 
        % Button_MC2_steering_wheel_angle_set_zero
        function Button_MC2_steering_wheel_angle_set_zeroPushed(app, event)
            app.MC2_maintenance_tool_driver.Steering_wheel_angle_set_zero;
        end

        % Button pushed function: 
        % Button_MC2_steering_wheel_angle_set_zero_old
        function Button_MC2_steering_wheel_angle_set_zero_oldPushed(app, event)
            app.MC2_maintenance_tool_driver.Steering_wheel_angle_set_zero_old;
        end

        % Button pushed function: Button_calculate_compensation
        function Button_calculate_compensationPushed(app, event)
            [file, path] = uigetfile('*.xls', '请选择本车最新的动态传动比文件', '动态传动比.xls');
            if isequal(file, 0)
                uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
            else
                % 计算结果的结论初始化
                calculated_result_need_save = false;
                
                % 计算MC1、MC2的软件方向盘与转向轴零位补偿值
                [app.EditField_MC1_steering_wheel_angle_compensation.Value, app.EditField_MC2_steering_wheel_angle_compensation.Value, app.EditField_MC1_steering_axle_angle_compensation.Value, app.EditField_MC2_steering_axle_angle_compensation.Value] = app.MC1_maintenance_tool_driver.Calculate_compensation(fullfile(path, file));

                % 根据补偿值的大小切换编辑框颜色，绿色代表无需写入控制器，黄色代表需要写入控制器
                if 5 > abs(app.EditField_MC1_steering_wheel_angle_compensation.Value)
                    app.EditField_MC1_steering_wheel_angle_compensation.BackgroundColor = [0, 1, 0]; % Green
                else
                    app.EditField_MC1_steering_wheel_angle_compensation.BackgroundColor = [1, 1, 0]; % Yellow
                    
                    % 计算结果需要写入控制器
                    calculated_result_need_save = true;

                    % 已连接机箱状态下，需要写入控制器时才使能写入按钮
                    if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.Button_save_steering_wheel_angle_compensation.Enable = true;
                    end
                end
                if 5 > abs(app.EditField_MC2_steering_wheel_angle_compensation.Value)
                    app.EditField_MC2_steering_wheel_angle_compensation.BackgroundColor = [0, 1, 0]; % Green
                else
                    app.EditField_MC2_steering_wheel_angle_compensation.BackgroundColor = [1, 1, 0]; % Yellow
                    
                    % 计算结果需要写入控制器
                    calculated_result_need_save = true;

                    % 已连接机箱状态下，需要写入控制器时才使能写入按钮
                    if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.Button_save_steering_wheel_angle_compensation.Enable = true;
                    end
                end
                if 0.3 > abs(app.EditField_MC1_steering_axle_angle_compensation.Value)
                    app.EditField_MC1_steering_axle_angle_compensation.BackgroundColor = [0, 1, 0]; % Green
                else
                    app.EditField_MC1_steering_axle_angle_compensation.BackgroundColor = [1, 1, 0]; % Yellow
                    
                    % 计算结果需要写入控制器
                    calculated_result_need_save = true;

                    % 已连接机箱状态下，需要写入控制器时才使能写入按钮
                    if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.Button_save_steering_wheel_angle_compensation.Enable = true;
                    end
                end
                if 0.3 > abs(app.EditField_MC2_steering_axle_angle_compensation.Value)
                    app.EditField_MC2_steering_axle_angle_compensation.BackgroundColor = [0, 1, 0]; % Green
                else
                    app.EditField_MC2_steering_axle_angle_compensation.BackgroundColor = [1, 1, 0]; % Yellow
                    
                    % 计算结果需要写入控制器
                    calculated_result_need_save = true;

                    % 已连接机箱状态下，需要写入控制器时才使能写入按钮
                    if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.Button_save_steering_wheel_angle_compensation.Enable = true;
                    end
                end

                % 弹窗提示计算结果是否需要写入控制器
                if calculated_result_need_save
                    calculated_result_text = ['方向盘软件零位标定参数计算结果不满足要求', newline, '需要写入控制器'];
                else
                    calculated_result_text = ['方向盘软件零位标定参数计算结果满足要求', newline, '不需要写入控制器'];
                end
                uialert(app.V14UIFigure, calculated_result_text, '提示', 'Icon', 'warning');
            end
        end

        % Close request function: V14UIFigure
        function V14UIFigureCloseRequest(app, event)
            % 关闭定时器
            if strcmp(get(app.timer1, 'Running'), 'on')
                stop(app.timer1);
            end

            % 释放定时器
            delete(app.timer1);

            % 清空内存中其他残留定时器
            delete(timerfindall);

            % 断开与控制器的Telnet连接
            if true == app.MC1_debug_part_enable
                if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.MC1_maintenance_tool_driver.Telnet_disconnect;
                end
                if isa(app.MC1_cycle_update_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.MC1_cycle_update_maintenance_tool_driver.Telnet_disconnect;
                end
            end
            if true == app.MC2_debug_part_enable
                if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.MC2_maintenance_tool_driver.Telnet_disconnect;
                end
                if isa(app.MC2_cycle_update_maintenance_tool_driver.telnet_object, 'tcpclient')
                    app.MC2_cycle_update_maintenance_tool_driver.Telnet_disconnect;
                end
            end

            % 释放app对象
            delete(app)

        end

        % Button pushed function: Button_factory_refresh
        function Button_factory_refreshPushed(app, event)
            [file, path] = uigetfile('*.zip', '请选择自动循迹控制软件目标码压缩包');
            if isequal(file, 0)
                uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
            else
                app.ADAS_software_zip_path = fullfile(path, file);
                % 弹出操作确认提示框
                confirm_message = sprintf('确认使用制造出厂刷新控制器ADAS软件吗?\n控制器内所有配置参数将恢复为出厂默认值！');
                uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Factory_refresh_callback(app, src, event));
            end
        end

        % Button pushed function: Button_after_sale_update
        function Button_after_sale_updatePushed(app, event)
            [file, path] = uigetfile('*.zip', '请选择自动循迹控制软件目标码压缩包');
            if isequal(file, 0)
                uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
            else
                app.ADAS_software_zip_path = fullfile(path, file);
                % 弹出操作确认提示框
                confirm_message = sprintf('确认使用售后维护刷新控制器ADAS软件吗?\n控制器内所有配置参数将保持不变！');
                uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)After_sale_update_callback(app, src, event));
            end
        end

        % Button pushed function: 
        % Button_save_steering_wheel_angle_compensation
        function Button_save_steering_wheel_angle_compensationPushed(app, event)
            % 弹出操作确认提示框
            confirm_message = sprintf('确认将方向盘软件零位标定参数写入控制器吗?\n重启后自动循迹性能将会改变！');
            uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Save_steering_wheel_angle_compensation_callback(app, src, event));
        end

        % Button pushed function: Button_save_A1_offset
        function Button_save_A1_offsetPushed(app, event)
            % 弹出操作确认提示框
            confirm_message = sprintf('确认将循迹摄像头航向标定参数写入控制器吗?\n重启后自动循迹性能将会改变！');
            uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Save_A1_offset_callback(app, src, event));
        end

        % Button pushed function: Button_MC1_A0_shift_left
        function Button_MC1_A0_shift_leftPushed(app, event)
            app.MC1_maintenance_tool_driver.Set_A0_offset(app.EditField_MC1_A0_offset.Value - 0.01);
            app.EditField_MC1_A0_offset.Value = app.MC1_maintenance_tool_driver.Get_A0_offset;
            app.EditField_MC1_A0_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_A0_offset.Enable = true;
            app.MC1_A0_offset_refresh_enable = true;
        end

        % Button pushed function: Button_MC1_A0_shift_right
        function Button_MC1_A0_shift_rightPushed(app, event)
            app.MC1_maintenance_tool_driver.Set_A0_offset(app.EditField_MC1_A0_offset.Value + 0.01);
            app.EditField_MC1_A0_offset.Value = app.MC1_maintenance_tool_driver.Get_A0_offset;
            app.EditField_MC1_A0_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_A0_offset.Enable = true;
            app.MC1_A0_offset_refresh_enable = true;
        end

        % Button pushed function: Button_MC2_A0_shift_left
        function Button_MC2_A0_shift_leftPushed(app, event)
            app.MC2_maintenance_tool_driver.Set_A0_offset(app.EditField_MC2_A0_offset.Value - 0.01);
            app.EditField_MC2_A0_offset.Value = app.MC2_maintenance_tool_driver.Get_A0_offset;
            app.EditField_MC2_A0_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_A0_offset.Enable = true;
            app.MC2_A0_offset_refresh_enable = true;
        end

        % Button pushed function: Button_MC2_A0_shift_right
        function Button_MC2_A0_shift_rightPushed(app, event)
            app.MC2_maintenance_tool_driver.Set_A0_offset(app.EditField_MC2_A0_offset.Value + 0.01);
            app.EditField_MC2_A0_offset.Value = app.MC2_maintenance_tool_driver.Get_A0_offset;
            app.EditField_MC2_A0_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_A0_offset.Enable = true;
            app.MC2_A0_offset_refresh_enable = true;
        end

        % Button pushed function: Button_save_A0_offset
        function Button_save_A0_offsetPushed(app, event)
            % 弹出操作确认提示框
            confirm_message = sprintf('确认将循迹摄像头轨迹偏差标定参数写入控制器吗?\n重启后自动循迹进站停靠间隙将会改变！');
            uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Save_A0_offset_callback(app, src, event));
        end

        % Button pushed function: Button_input_protocol_list_import
        function Button_input_protocol_list_importPushed(app, event)
            % 判断是否输入执行者，未输入则提醒
            if strcmp("", app.EditField_input_protocol_check_exerciser.Value)
                uialert(app.V14UIFigure, '请先输入执行者姓名！', '警告', 'Icon', 'warning');
            else
                % 导入输入协议清单文件
                [file, path] = uigetfile('*.xlsx', '请选择待核对的输入协议清单');
                if isequal(file, 0)
                    uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
                else
                    % 读取表格
                    app.input_protocol_list_table = readtable(fullfile(path, file), 'Sheet', '端口临时数据变量清单', 'PreserveVariableNames', true);

                    % 选择需要显示的内容显示到界面表格控件中
                    display_table = app.input_protocol_list_table(:, {'主索引', '数据名称', '最小值', '最大值'});
                    app.UITable_input_protocol_list.ColumnName = display_table.Properties.VariableNames;
                    app.UITable_input_protocol_list.Data = display_table;

                    % 生成输入协议核对记录表格
                    app.input_protocol_check_record = app.input_protocol_list_table(:, {'主索引', '数据名称'});
                    max_table_rows = max(app.input_protocol_check_record{:, 1});
                    app.input_protocol_check_record.('协议约定最大值') = app.input_protocol_list_table.('最大值');
                    app.input_protocol_check_record.('最大值核对结果') = strings(max_table_rows, 1);
                    app.input_protocol_check_record.('协议约定最小值') = app.input_protocol_list_table.('最小值');
                    app.input_protocol_check_record.('最小值核对结果') = strings(max_table_rows, 1);
                    app.input_protocol_check_record.('零值核对结果') = strings(max_table_rows, 1);
                    app.input_protocol_check_record.('执行者') = strings(max_table_rows, 1);
                    app.input_protocol_check_record.('执行者')(:) = app.EditField_input_protocol_check_exerciser.Value;
                    app.input_protocol_check_record.('时间记录') = strings(max_table_rows, 1);

                    % 使能导出记录按钮
                    app.Button_export_input_protocol_check_record.Enable = true;
                end
            end
        end

        % Button pushed function: 
        % Button_updata_input_protocol_channel_data
        function Button_updata_input_protocol_channel_dataPushed(app, event)
            value = app.DropDown_input_protocol_check_link_side.Value;
            if 0 == app.input_protocol_select_row
                uialert(app.V14UIFigure, '请先在表格中选择一个通道！', '警告', 'Icon', 'warning');
            else
                if strcmp("null", value)
                    uialert(app.V14UIFigure, '请先选择连接端！', '警告', 'Icon', 'warning');
                else
                    variable_name = app.input_protocol_list_table.('变量名')(app.input_protocol_select_row);
                    if strcmp("MC1", value)
                        [app.EditField_channel_current_value.Value, app.EditField_nonzero_channel_number.Value] = app.MC1_maintenance_tool_driver.Port_data_TRDP_protocol_input_check(variable_name);
                    else
                        [app.EditField_channel_current_value.Value, app.EditField_nonzero_channel_number.Value] = app.MC2_maintenance_tool_driver.Port_data_TRDP_protocol_input_check(variable_name);
                    end
                end
            end
        end

        % Cell selection callback: UITable_input_protocol_list
        function UITable_input_protocol_listCellSelection(app, event)
            indices = event.Indices;
            app.input_protocol_select_row = indices(1, 1);
            app.Button_updata_input_protocol_channel_data.Enable = true;
            app.Button_input_protocol_channel_comfirm_max.Enable = true;
            app.Button_input_protocol_channel_comfirm_min.Enable = false;
            app.Button_input_protocol_channel_comfirm_zero.Enable = false;
        end

        % Button pushed function: 
        % Button_input_protocol_channel_comfirm_max
        function Button_input_protocol_channel_comfirm_maxPushed(app, event)
            app.Button_input_protocol_channel_comfirm_max.Enable = false;
            app.Button_input_protocol_channel_comfirm_min.Enable = true;

            % 记录最大值核对结果
            app.input_protocol_check_record.('最大值核对结果')(app.input_protocol_select_row) = sprintf('%.13g', app.EditField_channel_current_value.Value);
        end

        % Button pushed function: 
        % Button_input_protocol_channel_comfirm_min
        function Button_input_protocol_channel_comfirm_minPushed(app, event)
            app.Button_input_protocol_channel_comfirm_min.Enable = false;
            app.Button_input_protocol_channel_comfirm_zero.Enable = true;

            % 记录最小值核对结果
            app.input_protocol_check_record.('最小值核对结果')(app.input_protocol_select_row) = sprintf('%.13g', app.EditField_channel_current_value.Value);
        end

        % Button pushed function: 
        % Button_input_protocol_channel_comfirm_zero
        function Button_input_protocol_channel_comfirm_zeroPushed(app, event)
            app.Button_input_protocol_channel_comfirm_zero.Enable = false;

            % 记录零值核对结果
            app.input_protocol_check_record.('零值核对结果')(app.input_protocol_select_row) = sprintf('%.13g', app.EditField_channel_current_value.Value);

            % 记录当前时间
            app.input_protocol_check_record.('时间记录')(app.input_protocol_select_row) = datetime('now');
        end

        % Button pushed function: 
        % Button_export_input_protocol_check_record
        function Button_export_input_protocol_check_recordPushed(app, event)
            [record_file, record_file_path] = uiputfile({'*.xlsx';'*.xls'}, '导出记录文件');
            if isequal(record_file, 0)
                uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
            else
                % 删除目标文件夹内上次输出的记录文件
                file_list = dir(record_file_path);
                index = strcmp({file_list(:).name}, record_file) == 1;
                search_result = file_list(index);
                if ~isempty(search_result)
                    delete(fullfile(record_file_path, record_file));
                end

                writetable(app.input_protocol_check_record, fullfile(record_file_path, record_file));
            end
        end

        % Button pushed function: Button_output_protocol_list_import
        function Button_output_protocol_list_importPushed(app, event)
            % 判断是否输入执行者，未输入则提醒
            if strcmp("", app.EditField_output_protocol_check_exerciser.Value)
                uialert(app.V14UIFigure, '请先输入执行者姓名！', '警告', 'Icon', 'warning');
            else
                % 导入输出协议清单文件
                [file, path] = uigetfile('*.xlsx', '请选择待核对的输出协议清单');
                if isequal(file, 0)
                    uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
                else
                    % 读取表格
                    app.output_protocol_list_table = readtable(fullfile(path, file), 'Sheet', '端口临时数据变量清单', 'PreserveVariableNames', true);

                    % 选择需要显示的内容显示到界面表格控件中
                    display_table = app.output_protocol_list_table(:, {'主索引', '数据名称', '最小值', '最大值'});
                    app.UITable_output_protocol_list.ColumnName = display_table.Properties.VariableNames;
                    app.UITable_output_protocol_list.Data = display_table;

                    % 生成输出协议核对记录表格
                    app.output_protocol_check_record = app.output_protocol_list_table(:, {'主索引', '数据名称'});
                    max_table_rows = max(app.output_protocol_check_record{:, 1});
                    app.output_protocol_check_record.('协议约定最大值') = app.output_protocol_list_table.('最大值');
                    app.output_protocol_check_record.('最大值核对结果') = strings(max_table_rows, 1);
                    app.output_protocol_check_record.('协议约定最小值') = app.output_protocol_list_table.('最小值');
                    app.output_protocol_check_record.('最小值核对结果') = strings(max_table_rows, 1);
                    app.output_protocol_check_record.('零值核对结果') = strings(max_table_rows, 1);
                    app.output_protocol_check_record.('执行者') = strings(max_table_rows, 1);
                    app.output_protocol_check_record.('执行者')(:) = app.EditField_output_protocol_check_exerciser.Value;
                    app.output_protocol_check_record.('时间记录') = strings(max_table_rows, 1);

                    % 使能导出记录按钮
                    app.Button_export_output_protocol_check_record.Enable = true;
                end
            end
        end

        % Cell selection callback: UITable_output_protocol_list
        function UITable_output_protocol_listCellSelection(app, event)
            indices = event.Indices;
            app.output_protocol_select_row = indices(1, 1);
            app.Button_output_protocol_channel_set_max.Enable = true;
            app.Button_output_protocol_channel_set_min.Enable = false;
            app.Button_output_protocol_channel_set_zero.Enable = false;
            app.Button_output_protocol_channel_comfirm_max.Enable = false;
            app.Button_output_protocol_channel_comfirm_min.Enable = false;
            app.Button_output_protocol_channel_comfirm_zero.Enable = false;
        end

        % Button pushed function: 
        % Button_output_protocol_channel_set_max
        function Button_output_protocol_channel_set_maxPushed(app, event)
            value = app.DropDown_output_protocol_check_link_side.Value;
            if strcmp("null", value)
                uialert(app.V14UIFigure, '请先选择连接端！', '警告', 'Icon', 'warning');
            else
                app.Button_output_protocol_channel_set_max.Enable = false;
                app.Button_output_protocol_channel_comfirm_max.Enable = true;

                % 根据连接端设置所选数据为最大值
                variable_name = app.output_protocol_list_table.('变量名')(app.output_protocol_select_row);
                if strcmp("MC1", value)
                    app.MC1_maintenance_tool_driver.Port_data_value_set_max(variable_name);
                else
                    app.MC2_maintenance_tool_driver.Port_data_value_set_max(variable_name);
                end
            end
        end

        % Button pushed function: 
        % Button_output_protocol_channel_comfirm_max
        function Button_output_protocol_channel_comfirm_maxPushed(app, event)
            app.Button_output_protocol_channel_comfirm_max.Enable = false;
            app.Button_output_protocol_channel_set_min.Enable = true;

            % 记录最大值核对结果
            app.output_protocol_check_record.('最大值核对结果')(app.output_protocol_select_row) = app.output_protocol_check_record.('协议约定最大值')(app.output_protocol_select_row);
        end

        % Button pushed function: 
        % Button_output_protocol_channel_set_min
        function Button_output_protocol_channel_set_minPushed(app, event)
            value = app.DropDown_output_protocol_check_link_side.Value;
            if strcmp("null", value)
                uialert(app.V14UIFigure, '请先选择连接端！', '警告', 'Icon', 'warning');
            else
                app.Button_output_protocol_channel_set_min.Enable = false;
                app.Button_output_protocol_channel_comfirm_min.Enable = true;

                % 根据连接端设置所选数据为最小值
                variable_name = app.output_protocol_list_table.('变量名')(app.output_protocol_select_row);
                if strcmp("MC1", value)
                    app.MC1_maintenance_tool_driver.Port_data_value_set_min(variable_name);
                else
                    app.MC2_maintenance_tool_driver.Port_data_value_set_min(variable_name);
                end
            end
        end

        % Button pushed function: 
        % Button_output_protocol_channel_comfirm_min
        function Button_output_protocol_channel_comfirm_minPushed(app, event)
            app.Button_output_protocol_channel_comfirm_min.Enable = false;
            app.Button_output_protocol_channel_set_zero.Enable = true;

            % 记录最小值核对结果
            app.output_protocol_check_record.('最小值核对结果')(app.output_protocol_select_row) = app.output_protocol_check_record.('协议约定最小值')(app.output_protocol_select_row);
        end

        % Button pushed function: 
        % Button_output_protocol_channel_set_zero
        function Button_output_protocol_channel_set_zeroPushed(app, event)
            value = app.DropDown_output_protocol_check_link_side.Value;
            if strcmp("null", value)
                uialert(app.V14UIFigure, '请先选择连接端！', '警告', 'Icon', 'warning');
            else
                app.Button_output_protocol_channel_set_zero.Enable = false;
                app.Button_output_protocol_channel_comfirm_zero.Enable = true;

                % 根据连接端设置所选数据为零
                variable_name = app.output_protocol_list_table.('变量名')(app.output_protocol_select_row);
                if strcmp("MC1", value)
                    app.MC1_maintenance_tool_driver.Port_data_value_set_zero(variable_name);
                else
                    app.MC2_maintenance_tool_driver.Port_data_value_set_zero(variable_name);
                end
            end
        end

        % Button pushed function: 
        % Button_output_protocol_channel_comfirm_zero
        function Button_output_protocol_channel_comfirm_zeroPushed(app, event)
            app.Button_output_protocol_channel_comfirm_zero.Enable = false;

            % 记录零值核对结果
            app.output_protocol_check_record.('零值核对结果')(app.output_protocol_select_row) = 0;

            % 记录当前时间
            app.output_protocol_check_record.('时间记录')(app.output_protocol_select_row) = datetime('now');
        end

        % Button pushed function: 
        % Button_export_output_protocol_check_record
        function Button_export_output_protocol_check_recordPushed(app, event)
            [record_file, record_file_path] = uiputfile({'*.xlsx';'*.xls'}, '导出记录文件');
            if isequal(record_file, 0)
                uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
            else
                % 删除目标文件夹内上次输出的记录文件
                file_list = dir(record_file_path);
                index = strcmp({file_list(:).name}, record_file) == 1;
                search_result = file_list(index);
                if ~isempty(search_result)
                    delete(fullfile(record_file_path, record_file));
                end

                writetable(app.output_protocol_check_record, fullfile(record_file_path, record_file));
            end
        end

        % Value changed function: 
        % DropDown_input_protocol_check_link_side
        function DropDown_input_protocol_check_link_sideValueChanged(app, event)
            value = app.DropDown_input_protocol_check_link_side.Value;
            if strcmp("null", value)
                if true == app.MC1_debug_part_enable
                    if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.MC1_maintenance_tool_driver.Output_port_mode_set(0);
                    end
                end
                if true == app.MC2_debug_part_enable
                    if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.MC2_maintenance_tool_driver.Output_port_mode_set(0);
                    end
                end
            elseif strcmp("MC1", value)
                if true == app.MC1_debug_part_enable
                    if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.MC1_maintenance_tool_driver.Output_port_mode_set(1);
                    end
                else
                    uialert(app.V14UIFigure, '未连接到MC1端！', '警告', 'Icon', 'warning');
                end
            elseif strcmp("MC2", value)
                if true == app.MC2_debug_part_enable
                    if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.MC2_maintenance_tool_driver.Output_port_mode_set(1);
                    end
                else
                    uialert(app.V14UIFigure, '未连接到MC2端！', '警告', 'Icon', 'warning');
                end
            end
        end

        % Value changed function: 
        % DropDown_output_protocol_check_link_side
        function DropDown_output_protocol_check_link_sideValueChanged(app, event)
            value = app.DropDown_output_protocol_check_link_side.Value;
            if strcmp("null", value)
                if true == app.MC1_debug_part_enable
                    if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.MC1_maintenance_tool_driver.Output_port_mode_set(0);
                    end
                end
                if true == app.MC2_debug_part_enable
                    if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.MC2_maintenance_tool_driver.Output_port_mode_set(0);
                    end
                end
            elseif strcmp("MC1", value)
                if true == app.MC1_debug_part_enable
                    if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.MC1_maintenance_tool_driver.Output_port_mode_set(2);
                    end
                else
                    uialert(app.V14UIFigure, '未连接到MC1端！', '警告', 'Icon', 'warning');
                end
            elseif strcmp("MC2", value)
                if true == app.MC2_debug_part_enable
                    if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.MC2_maintenance_tool_driver.Output_port_mode_set(2);
                    end
                else
                    uialert(app.V14UIFigure, '未连接到MC2端！', '警告', 'Icon', 'warning');
                end
            end
        end

        % Value changed function: DropDown_port_data_link_side
        function DropDown_port_data_link_sideValueChanged(app, event)
            value = app.DropDown_port_data_link_side.Value;
            if strcmp("MC1", value)
                if false == app.MC1_debug_part_enable
                    uialert(app.V14UIFigure, '未连接到MC1端！', '警告', 'Icon', 'warning');
                end
            elseif strcmp("MC2", value)
                if false == app.MC2_debug_part_enable
                    uialert(app.V14UIFigure, '未连接到MC2端！', '警告', 'Icon', 'warning');
                end
            end
        end

        % Button pushed function: Button_port_data_list_import
        function Button_port_data_list_importPushed(app, event)
            % 导入端口临时数据清单文件
            [file, path] = uigetfile('*.xlsx', '请选择待核对的输出协议清单');
            if isequal(file, 0)
                uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
            else
                % 读取表格
                app.port_data_list_table = readtable(fullfile(path, file), 'Sheet', '端口临时数据变量清单', 'PreserveVariableNames', true);

                % 选择需要显示的内容显示到界面表格控件中
                display_table = app.port_data_list_table(:, {'主索引', '数据名称', '最小值', '最大值'});
                app.UITable_port_data_list.ColumnName = display_table.Properties.VariableNames;
                app.UITable_port_data_list.Data = display_table;
            end
        end

        % Cell selection callback: UITable_port_data_list
        function UITable_port_data_listCellSelection(app, event)
            indices = event.Indices;
            app.port_data_select_row = indices(1, 1);
            app.Button_updata_port_data_channel_data.Enable = true;
            app.Button_confirm_set_port_data_value.Enable = true;
            app.Button_selected_port_data_channel_unlock.Enable = true;
        end

        % Button pushed function: 
        % Button_updata_port_data_channel_data
        function Button_updata_port_data_channel_dataPushed(app, event)
            value = app.DropDown_port_data_link_side.Value;
            if 0 == app.port_data_select_row
                uialert(app.V14UIFigure, '请先在表格中选择一个通道！', '警告', 'Icon', 'warning');
            else
                if strcmp("null", value)
                    uialert(app.V14UIFigure, '请先选择连接端！', '警告', 'Icon', 'warning');
                else
                    variable_name = app.port_data_list_table.('变量名')(app.port_data_select_row);
                    if strcmp("MC1", value)
                        app.EditField_selected_port_data_channel_current_value.Value = app.MC1_maintenance_tool_driver.Port_data_value_show_by_name(variable_name);
                    else
                        app.EditField_selected_port_data_channel_current_value.Value = app.MC2_maintenance_tool_driver.Port_data_value_show_by_name(variable_name);
                    end
                end
            end
        end

        % Button pushed function: Button_confirm_set_port_data_value
        function Button_confirm_set_port_data_valuePushed(app, event)
            set_value = sprintf('%.13g', app.EditField_selected_port_data_set_value.Value);
            link_side = app.DropDown_port_data_link_side.Value;
            if 0 == app.port_data_select_row
                uialert(app.V14UIFigure, '请先在表格中选择一个通道！', '警告', 'Icon', 'warning');
            else
                if strcmp("null", link_side)
                    uialert(app.V14UIFigure, '请先选择连接端！', '警告', 'Icon', 'warning');
                else
                    variable_name = app.port_data_list_table.('变量名')(app.port_data_select_row);
                    if strcmp("MC1", link_side)
                        app.MC1_maintenance_tool_driver.Port_data_value_set_by_name(variable_name, set_value);
                        pause(.5)
                        app.EditField_selected_port_data_channel_current_value.Value = app.MC1_maintenance_tool_driver.Port_data_value_show_by_name(variable_name);
                    else
                        app.MC2_maintenance_tool_driver.Port_data_value_set_by_name(variable_name, set_value);
                        pause(.5)
                        app.EditField_selected_port_data_channel_current_value.Value = app.MC2_maintenance_tool_driver.Port_data_value_show_by_name(variable_name);
                    end
                end
            end
        end

        % Button pushed function: 
        % Button_selected_port_data_channel_unlock
        function Button_selected_port_data_channel_unlockPushed(app, event)
            link_side = app.DropDown_port_data_link_side.Value;
            if 0 == app.port_data_select_row
                uialert(app.V14UIFigure, '请先在表格中选择一个通道！', '警告', 'Icon', 'warning');
            else
                if strcmp("null", link_side)
                    uialert(app.V14UIFigure, '请先选择连接端！', '警告', 'Icon', 'warning');
                else
                    variable_name = app.port_data_list_table.('变量名')(app.port_data_select_row);
                    if strcmp("MC1", link_side)
                        app.MC1_maintenance_tool_driver.Port_data_unlock_by_name(variable_name);
                        pause(.5)
                        app.EditField_selected_port_data_channel_current_value.Value = app.MC1_maintenance_tool_driver.Port_data_value_show_by_name(variable_name);
                    else
                        app.MC2_maintenance_tool_driver.Port_data_unlock_by_name(variable_name);
                        pause(.5)
                        app.EditField_selected_port_data_channel_current_value.Value = app.MC2_maintenance_tool_driver.Port_data_value_show_by_name(variable_name);
                    end
                end
            end
        end

        % Button pushed function: Button_MC1_A0_s1_shift_left
        function Button_MC1_A0_s1_shift_leftPushed(app, event)
            app.MC1_maintenance_tool_driver.Set_A0_s1_offset(app.EditField_MC1_A0_s1_offset.Value - 0.01);
            app.EditField_MC1_A0_s1_offset.Value = app.MC1_maintenance_tool_driver.Get_A0_s1_offset;
            app.EditField_MC1_A0_s1_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC1_A0_offset.Enable = true;
        end

        % Button pushed function: Button_MC1_A0_s1_shift_right
        function Button_MC1_A0_s1_shift_rightPushed(app, event)
            app.MC1_maintenance_tool_driver.Set_A0_s1_offset(app.EditField_MC1_A0_s1_offset.Value + 0.01);
            app.EditField_MC1_A0_s1_offset.Value = app.MC1_maintenance_tool_driver.Get_A0_s1_offset;
            app.EditField_MC1_A0_s1_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC1_A0_offset.Enable = true;
        end

        % Button pushed function: Button_MC1_A0_s2_shift_left
        function Button_MC1_A0_s2_shift_leftPushed(app, event)
            app.MC1_maintenance_tool_driver.Set_A0_s2_offset(app.EditField_MC1_A0_s2_offset.Value - 0.01);
            app.EditField_MC1_A0_s2_offset.Value = app.MC1_maintenance_tool_driver.Get_A0_s2_offset;
            app.EditField_MC1_A0_s2_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC1_A0_offset.Enable = true;
        end

        % Button pushed function: Button_MC1_A0_s2_shift_right
        function Button_MC1_A0_s2_shift_rightPushed(app, event)
            app.MC1_maintenance_tool_driver.Set_A0_s2_offset(app.EditField_MC1_A0_s2_offset.Value + 0.01);
            app.EditField_MC1_A0_s2_offset.Value = app.MC1_maintenance_tool_driver.Get_A0_s2_offset;
            app.EditField_MC1_A0_s2_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC1_A0_offset.Enable = true;
        end

        % Button pushed function: Button_save_YongXiu_MC1_A0_offset
        function Button_save_YongXiu_MC1_A0_offsetPushed(app, event)
            % 弹出操作确认提示框
            confirm_message = sprintf('确认将永修项目MC1端循迹摄像头轨迹偏差标定参数写入控制器吗?\n重启后自动循迹进站停靠间隙将会改变！');
            uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Save_YongXiu_MC1_A0_offset_callback(app, src, event));
        end

        % Button pushed function: Button_MC2_A0_s1_shift_left
        function Button_MC2_A0_s1_shift_leftPushed(app, event)
            app.MC2_maintenance_tool_driver.Set_A0_s1_offset(app.EditField_MC2_A0_s1_offset.Value - 0.01);
            app.EditField_MC2_A0_s1_offset.Value = app.MC2_maintenance_tool_driver.Get_A0_s1_offset;
            app.EditField_MC2_A0_s1_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC2_A0_offset.Enable = true;
        end

        % Button pushed function: Button_MC2_A0_s1_shift_right
        function Button_MC2_A0_s1_shift_rightPushed(app, event)
            app.MC2_maintenance_tool_driver.Set_A0_s1_offset(app.EditField_MC2_A0_s1_offset.Value + 0.01);
            app.EditField_MC2_A0_s1_offset.Value = app.MC2_maintenance_tool_driver.Get_A0_s1_offset;
            app.EditField_MC2_A0_s1_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC2_A0_offset.Enable = true;
        end

        % Button pushed function: Button_MC2_A0_s2_shift_left
        function Button_MC2_A0_s2_shift_leftPushed(app, event)
            app.MC2_maintenance_tool_driver.Set_A0_s2_offset(app.EditField_MC2_A0_s2_offset.Value - 0.01);
            app.EditField_MC2_A0_s2_offset.Value = app.MC2_maintenance_tool_driver.Get_A0_s2_offset;
            app.EditField_MC2_A0_s2_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC2_A0_offset.Enable = true;
        end

        % Button pushed function: Button_MC2_A0_s2_shift_right
        function Button_MC2_A0_s2_shift_rightPushed(app, event)
            app.MC2_maintenance_tool_driver.Set_A0_s2_offset(app.EditField_MC2_A0_s2_offset.Value + 0.01);
            app.EditField_MC2_A0_s2_offset.Value = app.MC2_maintenance_tool_driver.Get_A0_s2_offset;
            app.EditField_MC2_A0_s2_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC2_A0_offset.Enable = true;
        end

        % Button pushed function: Button_save_YongXiu_MC2_A0_offset
        function Button_save_YongXiu_MC2_A0_offsetPushed(app, event)
            % 弹出操作确认提示框
            confirm_message = sprintf('确认将永修项目MC2端循迹摄像头轨迹偏差标定参数写入控制器吗?\n重启后自动循迹进站停靠间隙将会改变！');
            uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Save_YongXiu_MC2_A0_offset_callback(app, src, event));
        end

        % Value changed function: EditField_MC1_A0_switch_threshold
        function EditField_MC1_A0_switch_thresholdValueChanged(app, event)
            app.EditField_MC1_A0_switch_threshold.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC1_A0_offset.Enable = true;
        end

        % Value changed function: EditField_MC2_A0_switch_threshold
        function EditField_MC2_A0_switch_thresholdValueChanged(app, event)
            app.EditField_MC2_A0_switch_threshold.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_YongXiu_MC2_A0_offset.Enable = true;
        end

        % Value changed function: EditField_MC1_task_index
        function EditField_MC1_task_indexValueChanged(app, event)
            value = app.EditField_MC1_task_index.Value;
            app.MC1_maintenance_tool_driver.Set_task_index(value);
            app.EditField_MC1_task_index.Value = app.MC1_maintenance_tool_driver.Get_task_index;
            app.EditField_MC1_task_index.BackgroundColor = [1, 1, 0]; % Yellow
        end

        % Value changed function: EditField_MC2_task_index
        function EditField_MC2_task_indexValueChanged(app, event)
            value = app.EditField_MC2_task_index.Value;
            app.MC2_maintenance_tool_driver.Set_task_index(value);
            app.EditField_MC2_task_index.Value = app.MC2_maintenance_tool_driver.Get_task_index;
            app.EditField_MC2_task_index.BackgroundColor = [1, 1, 0]; % Yellow
        end

        % Button pushed function: 
        % Button_MC1_reference_trajectory_refresh
        function Button_MC1_reference_trajectory_refreshPushed(app, event)
            % 开启本端车头参考轨迹的刷新使能
            app.MC1_trajectory_refresh_enable = true;

            % 选择压缩包，将其内容刷新到控制器中
            [file, path] = uigetfile('*.zip', '请选择参考轨迹专用压缩包');
            if isequal(file, 0)
                uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
            else
                app.reference_trajectory_zip_path = fullfile(path, file);

                % 弹出操作确认提示框
                confirm_message = sprintf('确认刷新MC1端控制器的参考轨迹吗?\n控制器内原有参考轨迹将被覆盖！');
                uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Reference_trajectory_refresh_callback(app, src, event));
            end
        end

        % Button pushed function: 
        % Button_MC2_reference_trajectory_refresh
        function Button_MC2_reference_trajectory_refreshPushed(app, event)
            % 开启本端车头参考轨迹的刷新使能
            app.MC2_trajectory_refresh_enable = true;

            % 选择压缩包，将其内容刷新到控制器中
            [file, path] = uigetfile('*.zip', '请选择参考轨迹专用压缩包');
            if isequal(file, 0)
                uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
            else
                app.reference_trajectory_zip_path = fullfile(path, file);

                % 弹出操作确认提示框
                confirm_message = sprintf('确认刷新MC2端控制器的参考轨迹吗?\n控制器内原有参考轨迹将被覆盖！');
                uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Reference_trajectory_refresh_callback(app, src, event));
            end
        end

        % Value changed function: EditField_MC1_grid_origin_latitude
        function EditField_MC1_grid_origin_latitudeValueChanged(app, event)
            value = app.EditField_MC1_grid_origin_latitude.Value;
            app.MC1_maintenance_tool_driver.Set_grid_origin_latitude(sprintf("%.11g", value));
            app.EditField_MC1_grid_origin_latitude.Value = app.MC1_maintenance_tool_driver.Get_grid_origin_latitude;
            app.EditField_MC1_grid_origin_latitude.BackgroundColor = [1, 1, 0]; % Yellow
        end

        % Value changed function: 
        % EditField_MC1_grid_origin_longitude
        function EditField_MC1_grid_origin_longitudeValueChanged(app, event)
            value = app.EditField_MC1_grid_origin_longitude.Value;
            app.MC1_maintenance_tool_driver.Set_grid_origin_longitude(sprintf("%.11g", value));
            app.EditField_MC1_grid_origin_longitude.Value = app.MC1_maintenance_tool_driver.Get_grid_origin_longitude;
            app.EditField_MC1_grid_origin_longitude.BackgroundColor = [1, 1, 0]; % Yellow
        end

        % Value changed function: EditField_MC2_grid_origin_latitude
        function EditField_MC2_grid_origin_latitudeValueChanged(app, event)
            value = app.EditField_MC2_grid_origin_latitude.Value;
            app.MC2_maintenance_tool_driver.Set_grid_origin_latitude(sprintf("%.11g", value));
            app.EditField_MC2_grid_origin_latitude.Value = app.MC2_maintenance_tool_driver.Get_grid_origin_latitude;
            app.EditField_MC2_grid_origin_latitude.BackgroundColor = [1, 1, 0]; % Yellow
        end

        % Value changed function: 
        % EditField_MC2_grid_origin_longitude
        function EditField_MC2_grid_origin_longitudeValueChanged(app, event)
            value = app.EditField_MC2_grid_origin_longitude.Value;
            app.MC2_maintenance_tool_driver.Set_grid_origin_longitude(sprintf("%.11g", value));
            app.EditField_MC2_grid_origin_longitude.Value = app.MC2_maintenance_tool_driver.Get_grid_origin_longitude;
            app.EditField_MC2_grid_origin_longitude.BackgroundColor = [1, 1, 0]; % Yellow
        end

        % Value changed function: 
        % EditField_MC1_reference_trajectory_index
        function EditField_MC1_reference_trajectory_indexValueChanged(app, event)
            value = app.EditField_MC1_reference_trajectory_index.Value;
            app.MC1_maintenance_tool_driver.Set_reference_trajectory_index(value);
            app.EditField_MC1_reference_trajectory_index.Value = app.MC1_maintenance_tool_driver.Get_reference_trajectory_index;
            app.EditField_MC1_reference_trajectory_index.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_reference_trajectory_index.Enable = true;
            app.MC1_trajectory_index_refresh_enable = true;
        end

        % Value changed function: 
        % EditField_MC2_reference_trajectory_index
        function EditField_MC2_reference_trajectory_indexValueChanged(app, event)
            value = app.EditField_MC2_reference_trajectory_index.Value;
            app.MC2_maintenance_tool_driver.Set_reference_trajectory_index(value);
            app.EditField_MC2_reference_trajectory_index.Value = app.MC2_maintenance_tool_driver.Get_reference_trajectory_index;
            app.EditField_MC2_reference_trajectory_index.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_reference_trajectory_index.Enable = true;
            app.MC2_trajectory_index_refresh_enable = true;
        end

        % Button pushed function: 
        % Button_save_reference_trajectory_index
        function Button_save_reference_trajectory_indexPushed(app, event)
            % 弹出操作确认提示框
            confirm_message = sprintf('确认将参考轨迹代号参数写入控制器吗?\n重启后列车跟随的参考轨迹将会改变！');
            uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Save_reference_trajectory_index_callback(app, src, event));
        end

        % Value changed function: EditField_MC1_yaw_angle_offset
        function EditField_MC1_yaw_angle_offsetValueChanged(app, event)
            value = app.EditField_MC1_yaw_angle_offset.Value;
            app.MC1_maintenance_tool_driver.Set_yaw_angle_offset(sprintf("%.6g", value));
            app.EditField_MC1_yaw_angle_offset.Value = app.MC1_maintenance_tool_driver.Get_yaw_angle_offset;
            app.EditField_MC1_yaw_angle_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_yaw_angle_offset.Enable = true;
            app.MC1_yaw_angle_offset_refresh_enable = true;
        end

        % Value changed function: EditField_MC2_yaw_angle_offset
        function EditField_MC2_yaw_angle_offsetValueChanged(app, event)
            value = app.EditField_MC2_yaw_angle_offset.Value;
            app.MC2_maintenance_tool_driver.Set_yaw_angle_offset(sprintf("%.6g", value));
            app.EditField_MC2_yaw_angle_offset.Value = app.MC2_maintenance_tool_driver.Get_yaw_angle_offset;
            app.EditField_MC2_yaw_angle_offset.BackgroundColor = [1, 1, 0]; % Yellow
            app.Button_save_yaw_angle_offset.Enable = true;
            app.MC2_yaw_angle_offset_refresh_enable = true;
        end

        % Button pushed function: Button_save_yaw_angle_offset
        function Button_save_yaw_angle_offsetPushed(app, event)
            % 弹出操作确认提示框
            confirm_message = sprintf('确认将组合惯导横摆标定参数写入控制器吗?\n重启后自动循迹性能将会改变！');
            uiconfirm(app.V14UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Save_yaw_angle_offset_callback(app, src, event));
        end

        % Button pushed function: 
        % Button_calculate_yaw_angle_compensation
        function Button_calculate_yaw_angle_compensationPushed(app, event)
            [file, path] = uigetfile('*.xls', '请选择本车最新的组合惯导软件零位标定文件', '组合惯导软件零位标定.xls');
            if isequal(file, 0)
                uialert(app.V14UIFigure, '未选择文件！', '警告', 'Icon', 'warning');
            else
                % 计算结果的结论初始化
                calculated_result_need_save = false;
                
                % 计算MC1、MC2的组合惯导横摆角软件零位补偿值
                [MC1_yaw_angle_compensation, MC2_yaw_angle_compensation] = app.MC1_maintenance_tool_driver.Calculate_yaw_angle_compensation(fullfile(path, file));

                % 根据计算得到的补偿值与原车补偿值之差的大小切换编辑框颜色，绿色代表无需写入控制器，黄色代表需要写入控制器
                if 0.05 > abs(MC1_yaw_angle_compensation - app.EditField_MC1_yaw_angle_offset.Value)
                    app.EditField_MC1_yaw_angle_offset.BackgroundColor = [0, 1, 0]; % Green
                else
                    app.EditField_MC1_yaw_angle_offset.BackgroundColor = [1, 1, 0]; % Yellow
                    
                    % 更新编辑框数值为计算得到的补偿值
                    app.EditField_MC1_yaw_angle_offset.Value = MC1_yaw_angle_compensation;
                    
                    % 后台修改数值无法触发编辑框数值修改回调函数，需要单独开启横摆角偏置刷新使能
                    app.MC1_yaw_angle_offset_refresh_enable = true;
                    
                    % 计算结果需要写入控制器
                    calculated_result_need_save = true;

                    % 已连接机箱状态下，需要写入控制器时才使能写入按钮
                    if isa(app.MC1_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.Button_save_yaw_angle_offset.Enable = true;
                    end
                end
                if 0.05 > abs(MC2_yaw_angle_compensation - app.EditField_MC2_yaw_angle_offset.Value)
                    app.EditField_MC2_yaw_angle_offset.BackgroundColor = [0, 1, 0]; % Green
                else
                    app.EditField_MC2_yaw_angle_offset.BackgroundColor = [1, 1, 0]; % Yellow
                    
                    % 更新编辑框数值为计算得到的补偿值
                    app.EditField_MC2_yaw_angle_offset.Value = MC2_yaw_angle_compensation;
                    
                    % 后台修改数值无法触发编辑框数值修改回调函数，需要单独开启横摆角偏置刷新使能
                    app.MC2_yaw_angle_offset_refresh_enable = true;
                    
                    % 计算结果需要写入控制器
                    calculated_result_need_save = true;

                    % 已连接机箱状态下，需要写入控制器时才使能写入按钮
                    if isa(app.MC2_maintenance_tool_driver.telnet_object, 'tcpclient')
                        app.Button_save_yaw_angle_offset.Enable = true;
                    end
                end
                
                % 弹窗提示计算结果是否需要写入控制器
                if calculated_result_need_save
                    calculated_result_text = ['组合惯导横摆标定参数计算结果不满足要求', newline, '需要写入控制器'];
                else
                    calculated_result_text = ['组合惯导横摆标定参数计算结果满足要求', newline, '不需要写入控制器'];
                end
                uialert(app.V14UIFigure, calculated_result_text, '提示', 'Icon', 'warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.V14UIFigure = uifigure('Visible', 'off');
            app.V14UIFigure.Name = '智轨电车驾驶辅助调试工具 V1.4';
            app.V14UIFigure.Icon = '中车LOGO.png';
            app.V14UIFigure.CloseRequestFcn = createCallbackFcn(app, @V14UIFigureCloseRequest, true);
            app.Button_connect = uibutton(app.V14UIFigure, 'state');
            app.Button_connect.ValueChangedFcn = createCallbackFcn(app, @Button_connectValueChanged, true);
            app.Button_connect.Text = '建立连接';
            app.Button_disconnect = uibutton(app.V14UIFigure, 'state');
            app.Button_disconnect.ValueChangedFcn = createCallbackFcn(app, @Button_disconnectValueChanged, true);
            app.Button_disconnect.Enable = 'off';
            app.Button_disconnect.Text = '断开连接';
            app.Label_MC1_connection_status = uilabel(app.V14UIFigure);
            app.Label_MC1_connection_status.Text = 'MC1已断开';
            app.Button_reboot = uibutton(app.V14UIFigure, 'push');
            app.Button_reboot.ButtonPushedFcn = createCallbackFcn(app, @Button_rebootPushed, true);
            app.Button_reboot.Enable = 'off';
            app.Button_reboot.Text = '热重启控制器';
            app.Label_MC2_connection_status = uilabel(app.V14UIFigure);
            app.Label_MC2_connection_status.Text = 'MC2已断开';
            app.Label_tram_type = uilabel(app.V14UIFigure);
            app.Label_tram_type.Text = '未选择车型';
            app.Label_MC1_lateral_control_software_version = uilabel(app.V14UIFigure);
            app.Label_MC1_lateral_control_software_version.Text = 'MC1循迹控制软件版本：未知';
            app.Label_MC2_lateral_control_software_version = uilabel(app.V14UIFigure);
            app.Label_MC2_lateral_control_software_version.Text = 'MC2循迹控制软件版本：未知';
            app.TabGroup = uitabgroup(app.V14UIFigure);
            app.Tab_parameter = uitab(app.TabGroup);
            app.Tab_parameter.Title = '参数设置';
            app.Panel_MC2_steering_wheel_zero_set = uipanel(app.Tab_parameter);
            app.Panel_MC2_steering_wheel_zero_set.Tooltip = {'设置MC2方向盘机械零位，解决手动驾驶时方向盘主动往一边转的问题，注意设置完成以后要重新测试方向盘软件零位标定参数是否符合要求'};
            app.Panel_MC2_steering_wheel_zero_set.Title = 'MC2方向盘机械零位设置';
            app.Button_MC2_steering_wheel_angle_set_zero = uibutton(app.Panel_MC2_steering_wheel_zero_set, 'push');
            app.Button_MC2_steering_wheel_angle_set_zero.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_steering_wheel_angle_set_zeroPushed, true);
            app.Button_MC2_steering_wheel_angle_set_zero.Enable = 'off';
            app.Button_MC2_steering_wheel_angle_set_zero.Text = '通用设置指令';
            app.Button_MC2_steering_wheel_angle_set_zero_old = uibutton(app.Panel_MC2_steering_wheel_zero_set, 'push');
            app.Button_MC2_steering_wheel_angle_set_zero_old.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_steering_wheel_angle_set_zero_oldPushed, true);
            app.Button_MC2_steering_wheel_angle_set_zero_old.Enable = 'off';
            app.Button_MC2_steering_wheel_angle_set_zero_old.Text = '备用设置指令';
            app.EditField_2Label = uilabel(app.Panel_MC2_steering_wheel_zero_set);
            app.EditField_2Label.Text = '实时方向盘转角';
            app.EditField_MC2_steering_wheel_angle = uieditfield(app.Panel_MC2_steering_wheel_zero_set, 'numeric');
            app.EditField_MC2_steering_wheel_angle.ValueDisplayFormat = '%11.4g °';
            app.EditField_MC2_steering_wheel_angle.Editable = 'off';
            app.Panel_camera_A0_compensation = uipanel(app.Tab_parameter);
            app.Panel_camera_A0_compensation.Tooltip = {'用于调整循迹进站停靠间隙，点左箭头是把车头往前进方向左侧调整，点右箭头是把车头往前进方向右侧调整，数值单位为m，调整步长为0.01m'};
            app.Panel_camera_A0_compensation.Title = '循迹摄像头轨迹偏差标定参数设置';
            app.Button_MC1_A0_shift_left = uibutton(app.Panel_camera_A0_compensation, 'push');
            app.Button_MC1_A0_shift_left.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_A0_shift_leftPushed, true);
            app.Button_MC1_A0_shift_left.Enable = 'off';
            app.Button_MC1_A0_shift_left.Text = '◀';
            app.Button_MC1_A0_shift_right = uibutton(app.Panel_camera_A0_compensation, 'push');
            app.Button_MC1_A0_shift_right.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_A0_shift_rightPushed, true);
            app.Button_MC1_A0_shift_right.Enable = 'off';
            app.Button_MC1_A0_shift_right.Text = '▶';
            app.MC1EditField_2Label_2 = uilabel(app.Panel_camera_A0_compensation);
            app.MC1EditField_2Label_2.Text = 'MC1';
            app.EditField_MC1_A0_offset = uieditfield(app.Panel_camera_A0_compensation, 'numeric');
            app.EditField_MC1_A0_offset.ValueDisplayFormat = '%11.5g';
            app.EditField_MC1_A0_offset.Editable = 'off';
            app.Button_MC2_A0_shift_left = uibutton(app.Panel_camera_A0_compensation, 'push');
            app.Button_MC2_A0_shift_left.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_A0_shift_leftPushed, true);
            app.Button_MC2_A0_shift_left.Enable = 'off';
            app.Button_MC2_A0_shift_left.Text = '◀';
            app.Button_MC2_A0_shift_right = uibutton(app.Panel_camera_A0_compensation, 'push');
            app.Button_MC2_A0_shift_right.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_A0_shift_rightPushed, true);
            app.Button_MC2_A0_shift_right.Enable = 'off';
            app.Button_MC2_A0_shift_right.Text = '▶';
            app.MC2EditField_2Label_2 = uilabel(app.Panel_camera_A0_compensation);
            app.MC2EditField_2Label_2.Text = 'MC2';
            app.EditField_MC2_A0_offset = uieditfield(app.Panel_camera_A0_compensation, 'numeric');
            app.EditField_MC2_A0_offset.ValueDisplayFormat = '%11.5g';
            app.EditField_MC2_A0_offset.Editable = 'off';
            app.Button_save_A0_offset = uibutton(app.Panel_camera_A0_compensation, 'push');
            app.Button_save_A0_offset.ButtonPushedFcn = createCallbackFcn(app, @Button_save_A0_offsetPushed, true);
            app.Button_save_A0_offset.Enable = 'off';
            app.Button_save_A0_offset.Text = '参数写入控制器';
            app.Panel_MC1_steering_wheel_zero_set = uipanel(app.Tab_parameter);
            app.Panel_MC1_steering_wheel_zero_set.Tooltip = {'设置MC1方向盘机械零位，解决手动驾驶时方向盘主动往一边转的问题，注意设置完成以后要重新测试方向盘软件零位标定参数是否符合要求'};
            app.Panel_MC1_steering_wheel_zero_set.Title = 'MC1方向盘机械零位设置';
            app.Button_MC1_steering_wheel_angle_set_zero = uibutton(app.Panel_MC1_steering_wheel_zero_set, 'push');
            app.Button_MC1_steering_wheel_angle_set_zero.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_steering_wheel_angle_set_zeroPushed, true);
            app.Button_MC1_steering_wheel_angle_set_zero.Enable = 'off';
            app.Button_MC1_steering_wheel_angle_set_zero.Text = '通用设置指令';
            app.Button_MC1_steering_wheel_angle_set_zero_old = uibutton(app.Panel_MC1_steering_wheel_zero_set, 'push');
            app.Button_MC1_steering_wheel_angle_set_zero_old.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_steering_wheel_angle_set_zero_oldPushed, true);
            app.Button_MC1_steering_wheel_angle_set_zero_old.Enable = 'off';
            app.Button_MC1_steering_wheel_angle_set_zero_old.Text = '备用设置指令';
            app.Label = uilabel(app.Panel_MC1_steering_wheel_zero_set);
            app.Label.Text = '实时方向盘转角';
            app.EditField_MC1_steering_wheel_angle = uieditfield(app.Panel_MC1_steering_wheel_zero_set, 'numeric');
            app.EditField_MC1_steering_wheel_angle.ValueDisplayFormat = '%11.4g °';
            app.EditField_MC1_steering_wheel_angle.Editable = 'off';
            app.Panel_camera_A1_compensation = uipanel(app.Tab_parameter);
            app.Panel_camera_A1_compensation.Tooltip = {'用于调整高速循迹横向偏差，点左箭头是把车头往前进方向左侧调整，点右箭头是把车头往前进方向右侧调整'};
            app.Panel_camera_A1_compensation.Title = '循迹摄像头航向标定参数设置';
            app.Button_MC1_A1_shift_left = uibutton(app.Panel_camera_A1_compensation, 'push');
            app.Button_MC1_A1_shift_left.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_A1_shift_leftPushed, true);
            app.Button_MC1_A1_shift_left.Enable = 'off';
            app.Button_MC1_A1_shift_left.Text = '◀';
            app.Button_MC1_A1_shift_right = uibutton(app.Panel_camera_A1_compensation, 'push');
            app.Button_MC1_A1_shift_right.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_A1_shift_rightPushed, true);
            app.Button_MC1_A1_shift_right.Enable = 'off';
            app.Button_MC1_A1_shift_right.Text = '▶';
            app.MC1EditField_2Label = uilabel(app.Panel_camera_A1_compensation);
            app.MC1EditField_2Label.Text = 'MC1';
            app.EditField_MC1_A1_offset = uieditfield(app.Panel_camera_A1_compensation, 'numeric');
            app.EditField_MC1_A1_offset.ValueDisplayFormat = '%11.5g';
            app.EditField_MC1_A1_offset.Editable = 'off';
            app.Button_MC2_A1_shift_left = uibutton(app.Panel_camera_A1_compensation, 'push');
            app.Button_MC2_A1_shift_left.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_A1_shift_leftPushed, true);
            app.Button_MC2_A1_shift_left.Enable = 'off';
            app.Button_MC2_A1_shift_left.Text = '◀';
            app.Button_MC2_A1_shift_right = uibutton(app.Panel_camera_A1_compensation, 'push');
            app.Button_MC2_A1_shift_right.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_A1_shift_rightPushed, true);
            app.Button_MC2_A1_shift_right.Enable = 'off';
            app.Button_MC2_A1_shift_right.Text = '▶';
            app.MC2EditField_2Label = uilabel(app.Panel_camera_A1_compensation);
            app.MC2EditField_2Label.Text = 'MC2';
            app.EditField_MC2_A1_offset = uieditfield(app.Panel_camera_A1_compensation, 'numeric');
            app.EditField_MC2_A1_offset.ValueDisplayFormat = '%11.5g';
            app.EditField_MC2_A1_offset.Editable = 'off';
            app.Button_save_A1_offset = uibutton(app.Panel_camera_A1_compensation, 'push');
            app.Button_save_A1_offset.ButtonPushedFcn = createCallbackFcn(app, @Button_save_A1_offsetPushed, true);
            app.Button_save_A1_offset.Enable = 'off';
            app.Button_save_A1_offset.Text = '参数写入控制器';
            app.Panel_software_update = uipanel(app.Tab_parameter);
            app.Panel_software_update.Tooltip = {'更新循迹控制软件，可按需选择“制造出厂”或“售后维护”'};
            app.Panel_software_update.Title = '自动循迹控制软件更新';
            app.Button_after_sale_update = uibutton(app.Panel_software_update, 'push');
            app.Button_after_sale_update.ButtonPushedFcn = createCallbackFcn(app, @Button_after_sale_updatePushed, true);
            app.Button_after_sale_update.Enable = 'off';
            app.Button_after_sale_update.Text = '售后维护';
            app.Button_factory_refresh = uibutton(app.Panel_software_update, 'push');
            app.Button_factory_refresh.ButtonPushedFcn = createCallbackFcn(app, @Button_factory_refreshPushed, true);
            app.Button_factory_refresh.Enable = 'off';
            app.Button_factory_refresh.Text = '制造出厂';
            app.Panel_steering_wheel_zero_compensation = uipanel(app.Tab_parameter);
            app.Panel_steering_wheel_zero_compensation.Tooltip = {'测试方向盘软件零位标定参数，如果自动计算结果不满足要求，数值框会变黄，参数写入控制器按钮变成可以点击的'};
            app.Panel_steering_wheel_zero_compensation.Title = '方向盘软件零位标定参数设置';
            app.Button_calculate_compensation = uibutton(app.Panel_steering_wheel_zero_compensation, 'push');
            app.Button_calculate_compensation.ButtonPushedFcn = createCallbackFcn(app, @Button_calculate_compensationPushed, true);
            app.Button_calculate_compensation.Text = '计算零位标定参数补偿值';
            app.MC1EditFieldLabel = uilabel(app.Panel_steering_wheel_zero_compensation);
            app.MC1EditFieldLabel.Text = 'MC1';
            app.EditField_MC1_steering_wheel_angle_compensation = uieditfield(app.Panel_steering_wheel_zero_compensation, 'numeric');
            app.EditField_MC1_steering_wheel_angle_compensation.Editable = 'off';
            app.MC2EditFieldLabel = uilabel(app.Panel_steering_wheel_zero_compensation);
            app.MC2EditFieldLabel.Text = 'MC2';
            app.EditField_MC2_steering_wheel_angle_compensation = uieditfield(app.Panel_steering_wheel_zero_compensation, 'numeric');
            app.EditField_MC2_steering_wheel_angle_compensation.Editable = 'off';
            app.Button_save_steering_wheel_angle_compensation = uibutton(app.Panel_steering_wheel_zero_compensation, 'push');
            app.Button_save_steering_wheel_angle_compensation.ButtonPushedFcn = createCallbackFcn(app, @Button_save_steering_wheel_angle_compensationPushed, true);
            app.Button_save_steering_wheel_angle_compensation.Enable = 'off';
            app.Button_save_steering_wheel_angle_compensation.Text = '参数写入控制器';
            app.Label_21 = uilabel(app.Panel_steering_wheel_zero_compensation);
            app.Label_21.Text = '轴1';
            app.EditField_MC1_steering_axle_angle_compensation = uieditfield(app.Panel_steering_wheel_zero_compensation, 'numeric');
            app.EditField_MC1_steering_axle_angle_compensation.Editable = 'off';
            app.Label_22 = uilabel(app.Panel_steering_wheel_zero_compensation);
            app.Label_22.Text = '轴6';
            app.EditField_MC2_steering_axle_angle_compensation = uieditfield(app.Panel_steering_wheel_zero_compensation, 'numeric');
            app.EditField_MC2_steering_axle_angle_compensation.Editable = 'off';
            app.Panel_record_flag = uipanel(app.Tab_parameter);
            app.Panel_record_flag.Tooltip = {'设置任务代号，作为数据记录断点标识，便于回溯数据'};
            app.Panel_record_flag.Title = '数据记录特征标识';
            app.MC1Label = uilabel(app.Panel_record_flag);
            app.MC1Label.Text = 'MC1任务代号';
            app.EditField_MC1_task_index = uieditfield(app.Panel_record_flag, 'numeric');
            app.EditField_MC1_task_index.Limits = [0 255];
            app.EditField_MC1_task_index.RoundFractionalValues = 'on';
            app.EditField_MC1_task_index.ValueDisplayFormat = '%d';
            app.EditField_MC1_task_index.ValueChangedFcn = createCallbackFcn(app, @EditField_MC1_task_indexValueChanged, true);
            app.EditField_MC1_task_index.Editable = 'off';
            app.EditField_MC1_task_index.Tooltip = {'设置数据记录断点标识，便于回溯数据'};
            app.MC2Label = uilabel(app.Panel_record_flag);
            app.MC2Label.Text = 'MC2任务代号';
            app.EditField_MC2_task_index = uieditfield(app.Panel_record_flag, 'numeric');
            app.EditField_MC2_task_index.Limits = [0 255];
            app.EditField_MC2_task_index.RoundFractionalValues = 'on';
            app.EditField_MC2_task_index.ValueDisplayFormat = '%d';
            app.EditField_MC2_task_index.ValueChangedFcn = createCallbackFcn(app, @EditField_MC2_task_indexValueChanged, true);
            app.EditField_MC2_task_index.Editable = 'off';
            app.EditField_MC2_task_index.Tooltip = {'设置数据记录断点标识，便于回溯数据'};
            app.Panel_GNSS_yaw_angle_compensation = uipanel(app.Tab_parameter);
            app.Panel_GNSS_yaw_angle_compensation.Tooltip = {'组合惯导横摆固有偏差标定'};
            app.Panel_GNSS_yaw_angle_compensation.Title = '组合惯导横摆标定参数设置';
            app.MC1Label_2 = uilabel(app.Panel_GNSS_yaw_angle_compensation);
            app.MC1Label_2.Text = 'MC1';
            app.EditField_MC1_yaw_angle_offset = uieditfield(app.Panel_GNSS_yaw_angle_compensation, 'numeric');
            app.EditField_MC1_yaw_angle_offset.Limits = [-90 90];
            app.EditField_MC1_yaw_angle_offset.ValueDisplayFormat = '%.6g °';
            app.EditField_MC1_yaw_angle_offset.ValueChangedFcn = createCallbackFcn(app, @EditField_MC1_yaw_angle_offsetValueChanged, true);
            app.EditField_MC1_yaw_angle_offset.Editable = 'off';
            app.EditField_MC1_yaw_angle_offset.Tooltip = {'实时设置主控板里的惯导横摆角偏置'};
            app.MC2Label_2 = uilabel(app.Panel_GNSS_yaw_angle_compensation);
            app.MC2Label_2.Text = 'MC2';
            app.EditField_MC2_yaw_angle_offset = uieditfield(app.Panel_GNSS_yaw_angle_compensation, 'numeric');
            app.EditField_MC2_yaw_angle_offset.Limits = [-90 90];
            app.EditField_MC2_yaw_angle_offset.ValueDisplayFormat = '%.6g °';
            app.EditField_MC2_yaw_angle_offset.ValueChangedFcn = createCallbackFcn(app, @EditField_MC2_yaw_angle_offsetValueChanged, true);
            app.EditField_MC2_yaw_angle_offset.Editable = 'off';
            app.EditField_MC2_yaw_angle_offset.Tooltip = {'实时设置主控板里的惯导横摆角偏置'};
            app.Button_save_yaw_angle_offset = uibutton(app.Panel_GNSS_yaw_angle_compensation, 'push');
            app.Button_save_yaw_angle_offset.ButtonPushedFcn = createCallbackFcn(app, @Button_save_yaw_angle_offsetPushed, true);
            app.Button_save_yaw_angle_offset.Enable = 'off';
            app.Button_save_yaw_angle_offset.Text = '参数写入控制器';
            app.Button_calculate_yaw_angle_compensation = uibutton(app.Panel_GNSS_yaw_angle_compensation, 'push');
            app.Button_calculate_yaw_angle_compensation.ButtonPushedFcn = createCallbackFcn(app, @Button_calculate_yaw_angle_compensationPushed, true);
            app.Button_calculate_yaw_angle_compensation.Text = '计算零位标定参数补偿值';
            app.Tab_input_protocol_check = uitab(app.TabGroup);
            app.Tab_input_protocol_check.Title = '输入协议核对';
            app.UITable_input_protocol_list = uitable(app.Tab_input_protocol_check);
            app.UITable_input_protocol_list.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable_input_protocol_list.RowName = {};
            app.UITable_input_protocol_list.CellSelectionCallback = createCallbackFcn(app, @UITable_input_protocol_listCellSelection, true);
            app.Button_input_protocol_list_import = uibutton(app.Tab_input_protocol_check, 'push');
            app.Button_input_protocol_list_import.ButtonPushedFcn = createCallbackFcn(app, @Button_input_protocol_list_importPushed, true);
            app.Button_input_protocol_list_import.Text = '输入协议清单导入';
            app.Label_2 = uilabel(app.Tab_input_protocol_check);
            app.Label_2.Text = '执行者';
            app.EditField_input_protocol_check_exerciser = uieditfield(app.Tab_input_protocol_check, 'text');
            app.Label_3 = uilabel(app.Tab_input_protocol_check);
            app.Label_3.Text = '选定通道当前真实值';
            app.EditField_channel_current_value = uieditfield(app.Tab_input_protocol_check, 'numeric');
            app.EditField_channel_current_value.ValueDisplayFormat = '%.13g';
            app.EditField_channel_current_value.Editable = 'off';
            app.Label_4 = uilabel(app.Tab_input_protocol_check);
            app.Label_4.Text = '真实值非零的其他通道数量';
            app.EditField_nonzero_channel_number = uieditfield(app.Tab_input_protocol_check, 'numeric');
            app.EditField_nonzero_channel_number.Editable = 'off';
            app.Button_updata_input_protocol_channel_data = uibutton(app.Tab_input_protocol_check, 'push');
            app.Button_updata_input_protocol_channel_data.ButtonPushedFcn = createCallbackFcn(app, @Button_updata_input_protocol_channel_dataPushed, true);
            app.Button_updata_input_protocol_channel_data.Enable = 'off';
            app.Button_updata_input_protocol_channel_data.Text = '更新选定通道数据';
            app.Button_input_protocol_channel_comfirm_max = uibutton(app.Tab_input_protocol_check, 'push');
            app.Button_input_protocol_channel_comfirm_max.ButtonPushedFcn = createCallbackFcn(app, @Button_input_protocol_channel_comfirm_maxPushed, true);
            app.Button_input_protocol_channel_comfirm_max.Enable = 'off';
            app.Button_input_protocol_channel_comfirm_max.Text = '确认为最大值';
            app.Button_input_protocol_channel_comfirm_min = uibutton(app.Tab_input_protocol_check, 'push');
            app.Button_input_protocol_channel_comfirm_min.ButtonPushedFcn = createCallbackFcn(app, @Button_input_protocol_channel_comfirm_minPushed, true);
            app.Button_input_protocol_channel_comfirm_min.Enable = 'off';
            app.Button_input_protocol_channel_comfirm_min.Text = '确认为最小值';
            app.Button_input_protocol_channel_comfirm_zero = uibutton(app.Tab_input_protocol_check, 'push');
            app.Button_input_protocol_channel_comfirm_zero.ButtonPushedFcn = createCallbackFcn(app, @Button_input_protocol_channel_comfirm_zeroPushed, true);
            app.Button_input_protocol_channel_comfirm_zero.Enable = 'off';
            app.Button_input_protocol_channel_comfirm_zero.Text = '确认为零';
            app.Button_export_input_protocol_check_record = uibutton(app.Tab_input_protocol_check, 'push');
            app.Button_export_input_protocol_check_record.ButtonPushedFcn = createCallbackFcn(app, @Button_export_input_protocol_check_recordPushed, true);
            app.Button_export_input_protocol_check_record.Enable = 'off';
            app.Button_export_input_protocol_check_record.Text = '导出输入协议核对记录';
            app.Label_6 = uilabel(app.Tab_input_protocol_check);
            app.Label_6.Text = '连接端';
            app.DropDown_input_protocol_check_link_side = uidropdown(app.Tab_input_protocol_check);
            app.DropDown_input_protocol_check_link_side.Items = {'', 'MC1', 'MC2'};
            app.DropDown_input_protocol_check_link_side.ItemsData = {'null', 'MC1', 'MC2'};
            app.DropDown_input_protocol_check_link_side.ValueChangedFcn = createCallbackFcn(app, @DropDown_input_protocol_check_link_sideValueChanged, true);
            app.DropDown_input_protocol_check_link_side.Value = 'null';
            app.Tab_output_protocol_check = uitab(app.TabGroup);
            app.Tab_output_protocol_check.Title = '输出协议核对';
            app.Button_output_protocol_list_import = uibutton(app.Tab_output_protocol_check, 'push');
            app.Button_output_protocol_list_import.ButtonPushedFcn = createCallbackFcn(app, @Button_output_protocol_list_importPushed, true);
            app.Button_output_protocol_list_import.Text = '输出协议清单导入';
            app.Button_export_output_protocol_check_record = uibutton(app.Tab_output_protocol_check, 'push');
            app.Button_export_output_protocol_check_record.ButtonPushedFcn = createCallbackFcn(app, @Button_export_output_protocol_check_recordPushed, true);
            app.Button_export_output_protocol_check_record.Enable = 'off';
            app.Button_export_output_protocol_check_record.Text = '导出输出协议核对记录';
            app.Label_5 = uilabel(app.Tab_output_protocol_check);
            app.Label_5.Text = '执行者';
            app.EditField_output_protocol_check_exerciser = uieditfield(app.Tab_output_protocol_check, 'text');
            app.UITable_output_protocol_list = uitable(app.Tab_output_protocol_check);
            app.UITable_output_protocol_list.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable_output_protocol_list.RowName = {};
            app.UITable_output_protocol_list.CellSelectionCallback = createCallbackFcn(app, @UITable_output_protocol_listCellSelection, true);
            app.Panel_output_data_set = uipanel(app.Tab_output_protocol_check);
            app.Panel_output_data_set.Title = '输出数据设置';
            app.Button_output_protocol_channel_set_max = uibutton(app.Panel_output_data_set, 'push');
            app.Button_output_protocol_channel_set_max.ButtonPushedFcn = createCallbackFcn(app, @Button_output_protocol_channel_set_maxPushed, true);
            app.Button_output_protocol_channel_set_max.Enable = 'off';
            app.Button_output_protocol_channel_set_max.Text = '设置为最大值';
            app.Button_output_protocol_channel_set_min = uibutton(app.Panel_output_data_set, 'push');
            app.Button_output_protocol_channel_set_min.ButtonPushedFcn = createCallbackFcn(app, @Button_output_protocol_channel_set_minPushed, true);
            app.Button_output_protocol_channel_set_min.Enable = 'off';
            app.Button_output_protocol_channel_set_min.Text = '设置为最小值';
            app.Button_output_protocol_channel_set_zero = uibutton(app.Panel_output_data_set, 'push');
            app.Button_output_protocol_channel_set_zero.ButtonPushedFcn = createCallbackFcn(app, @Button_output_protocol_channel_set_zeroPushed, true);
            app.Button_output_protocol_channel_set_zero.Enable = 'off';
            app.Button_output_protocol_channel_set_zero.Text = '设置为零';
            app.Panel_related_system_input_data_confirm = uipanel(app.Tab_output_protocol_check);
            app.Panel_related_system_input_data_confirm.Title = '关联系统接收数据确认';
            app.Button_output_protocol_channel_comfirm_max = uibutton(app.Panel_related_system_input_data_confirm, 'push');
            app.Button_output_protocol_channel_comfirm_max.ButtonPushedFcn = createCallbackFcn(app, @Button_output_protocol_channel_comfirm_maxPushed, true);
            app.Button_output_protocol_channel_comfirm_max.Enable = 'off';
            app.Button_output_protocol_channel_comfirm_max.Text = '确认为最大值';
            app.Button_output_protocol_channel_comfirm_min = uibutton(app.Panel_related_system_input_data_confirm, 'push');
            app.Button_output_protocol_channel_comfirm_min.ButtonPushedFcn = createCallbackFcn(app, @Button_output_protocol_channel_comfirm_minPushed, true);
            app.Button_output_protocol_channel_comfirm_min.Enable = 'off';
            app.Button_output_protocol_channel_comfirm_min.Text = '确认为最小值';
            app.Button_output_protocol_channel_comfirm_zero = uibutton(app.Panel_related_system_input_data_confirm, 'push');
            app.Button_output_protocol_channel_comfirm_zero.ButtonPushedFcn = createCallbackFcn(app, @Button_output_protocol_channel_comfirm_zeroPushed, true);
            app.Button_output_protocol_channel_comfirm_zero.Enable = 'off';
            app.Button_output_protocol_channel_comfirm_zero.Text = '确认为零';
            app.Label_7 = uilabel(app.Tab_output_protocol_check);
            app.Label_7.Text = '连接端';
            app.DropDown_output_protocol_check_link_side = uidropdown(app.Tab_output_protocol_check);
            app.DropDown_output_protocol_check_link_side.Items = {'', 'MC1', 'MC2'};
            app.DropDown_output_protocol_check_link_side.ItemsData = {'null', 'MC1', 'MC2'};
            app.DropDown_output_protocol_check_link_side.ValueChangedFcn = createCallbackFcn(app, @DropDown_output_protocol_check_link_sideValueChanged, true);
            app.DropDown_output_protocol_check_link_side.Value = 'null';
            app.Tab_port_data_interaction = uitab(app.TabGroup);
            app.Tab_port_data_interaction.Title = '端口临时数据交互';
            app.UITable_port_data_list = uitable(app.Tab_port_data_interaction);
            app.UITable_port_data_list.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable_port_data_list.RowName = {};
            app.UITable_port_data_list.CellSelectionCallback = createCallbackFcn(app, @UITable_port_data_listCellSelection, true);
            app.Label_8 = uilabel(app.Tab_port_data_interaction);
            app.Label_8.Text = '连接端';
            app.DropDown_port_data_link_side = uidropdown(app.Tab_port_data_interaction);
            app.DropDown_port_data_link_side.Items = {'', 'MC1', 'MC2'};
            app.DropDown_port_data_link_side.ItemsData = {'null', 'MC1', 'MC2'};
            app.DropDown_port_data_link_side.ValueChangedFcn = createCallbackFcn(app, @DropDown_port_data_link_sideValueChanged, true);
            app.DropDown_port_data_link_side.Value = 'null';
            app.Button_port_data_list_import = uibutton(app.Tab_port_data_interaction, 'push');
            app.Button_port_data_list_import.ButtonPushedFcn = createCallbackFcn(app, @Button_port_data_list_importPushed, true);
            app.Button_port_data_list_import.Text = '端口临时数据清单导入';
            app.Button_updata_port_data_channel_data = uibutton(app.Tab_port_data_interaction, 'push');
            app.Button_updata_port_data_channel_data.ButtonPushedFcn = createCallbackFcn(app, @Button_updata_port_data_channel_dataPushed, true);
            app.Button_updata_port_data_channel_data.Enable = 'off';
            app.Button_updata_port_data_channel_data.Text = '更新选定通道数据';
            app.Button_confirm_set_port_data_value = uibutton(app.Tab_port_data_interaction, 'push');
            app.Button_confirm_set_port_data_value.ButtonPushedFcn = createCallbackFcn(app, @Button_confirm_set_port_data_valuePushed, true);
            app.Button_confirm_set_port_data_value.Enable = 'off';
            app.Button_confirm_set_port_data_value.Text = '确认设置';
            app.Button_selected_port_data_channel_unlock = uibutton(app.Tab_port_data_interaction, 'push');
            app.Button_selected_port_data_channel_unlock.ButtonPushedFcn = createCallbackFcn(app, @Button_selected_port_data_channel_unlockPushed, true);
            app.Button_selected_port_data_channel_unlock.Enable = 'off';
            app.Button_selected_port_data_channel_unlock.Text = '选定通道解锁';
            app.Label_9 = uilabel(app.Tab_port_data_interaction);
            app.Label_9.Text = '选定通道当前真实值';
            app.EditField_selected_port_data_channel_current_value = uieditfield(app.Tab_port_data_interaction, 'numeric');
            app.EditField_selected_port_data_channel_current_value.ValueDisplayFormat = '%.13g';
            app.EditField_selected_port_data_channel_current_value.Editable = 'off';
            app.Label_10 = uilabel(app.Tab_port_data_interaction);
            app.Label_10.Text = '设置选定通道真实值';
            app.EditField_selected_port_data_set_value = uieditfield(app.Tab_port_data_interaction, 'numeric');
            app.EditField_selected_port_data_set_value.ValueDisplayFormat = '%.13g';
            app.Tab_YongXiu_A0_modify = uitab(app.TabGroup);
            app.Tab_YongXiu_A0_modify.Title = '永修间隙调整';
            app.Panel_MC1_camera_A0_compensation = uipanel(app.Tab_YongXiu_A0_modify);
            app.Panel_MC1_camera_A0_compensation.Tooltip = {'用于调整循迹进站停靠间隙，点左箭头是把车头往前进方向左侧调整，点右箭头是把车头往前进方向右侧调整，数值单位为m，调整步长为0.01m。第1阶段是指当前牵引端从起点站发车到线路中间十字路口直角转弯之间的线路区间，第2阶段是指从中间十字路口直角转弯到终点站之间的线路区间。'};
            app.Panel_MC1_camera_A0_compensation.Title = 'MC1循迹摄像头轨迹偏差标定参数设置';
            app.Button_MC1_A0_s1_shift_left = uibutton(app.Panel_MC1_camera_A0_compensation, 'push');
            app.Button_MC1_A0_s1_shift_left.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_A0_s1_shift_leftPushed, true);
            app.Button_MC1_A0_s1_shift_left.Enable = 'off';
            app.Button_MC1_A0_s1_shift_left.Text = '◀';
            app.Button_MC1_A0_s1_shift_right = uibutton(app.Panel_MC1_camera_A0_compensation, 'push');
            app.Button_MC1_A0_s1_shift_right.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_A0_s1_shift_rightPushed, true);
            app.Button_MC1_A0_s1_shift_right.Enable = 'off';
            app.Button_MC1_A0_s1_shift_right.Text = '▶';
            app.Label_11 = uilabel(app.Panel_MC1_camera_A0_compensation);
            app.Label_11.Text = '第1阶段';
            app.EditField_MC1_A0_s1_offset = uieditfield(app.Panel_MC1_camera_A0_compensation, 'numeric');
            app.EditField_MC1_A0_s1_offset.ValueDisplayFormat = '%11.5g';
            app.EditField_MC1_A0_s1_offset.Editable = 'off';
            app.Button_MC1_A0_s2_shift_left = uibutton(app.Panel_MC1_camera_A0_compensation, 'push');
            app.Button_MC1_A0_s2_shift_left.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_A0_s2_shift_leftPushed, true);
            app.Button_MC1_A0_s2_shift_left.Enable = 'off';
            app.Button_MC1_A0_s2_shift_left.Text = '◀';
            app.Button_MC1_A0_s2_shift_right = uibutton(app.Panel_MC1_camera_A0_compensation, 'push');
            app.Button_MC1_A0_s2_shift_right.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_A0_s2_shift_rightPushed, true);
            app.Button_MC1_A0_s2_shift_right.Enable = 'off';
            app.Button_MC1_A0_s2_shift_right.Text = '▶';
            app.Label_12 = uilabel(app.Panel_MC1_camera_A0_compensation);
            app.Label_12.Text = '第2阶段';
            app.EditField_MC1_A0_s2_offset = uieditfield(app.Panel_MC1_camera_A0_compensation, 'numeric');
            app.EditField_MC1_A0_s2_offset.ValueDisplayFormat = '%11.5g';
            app.EditField_MC1_A0_s2_offset.Editable = 'off';
            app.Button_save_YongXiu_MC1_A0_offset = uibutton(app.Panel_MC1_camera_A0_compensation, 'push');
            app.Button_save_YongXiu_MC1_A0_offset.ButtonPushedFcn = createCallbackFcn(app, @Button_save_YongXiu_MC1_A0_offsetPushed, true);
            app.Button_save_YongXiu_MC1_A0_offset.Enable = 'off';
            app.Button_save_YongXiu_MC1_A0_offset.Text = '参数写入控制器';
            app.Label_15 = uilabel(app.Panel_MC1_camera_A0_compensation);
            app.Label_15.Text = '切换阈值';
            app.EditField_MC1_A0_switch_threshold = uieditfield(app.Panel_MC1_camera_A0_compensation, 'numeric');
            app.EditField_MC1_A0_switch_threshold.ValueDisplayFormat = '%11.5g';
            app.EditField_MC1_A0_switch_threshold.ValueChangedFcn = createCallbackFcn(app, @EditField_MC1_A0_switch_thresholdValueChanged, true);
            app.Panel_MC2_camera_A0_compensation = uipanel(app.Tab_YongXiu_A0_modify);
            app.Panel_MC2_camera_A0_compensation.Tooltip = {'用于调整循迹进站停靠间隙，点左箭头是把车头往前进方向左侧调整，点右箭头是把车头往前进方向右侧调整，数值单位为m，调整步长为0.01m。第1阶段是指当前牵引端从起点站发车到线路中间十字路口直角转弯之间的线路区间，第2阶段是指从中间十字路口直角转弯到终点站之间的线路区间。'};
            app.Panel_MC2_camera_A0_compensation.Title = 'MC2循迹摄像头轨迹偏差标定参数设置';
            app.Button_MC2_A0_s1_shift_left = uibutton(app.Panel_MC2_camera_A0_compensation, 'push');
            app.Button_MC2_A0_s1_shift_left.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_A0_s1_shift_leftPushed, true);
            app.Button_MC2_A0_s1_shift_left.Enable = 'off';
            app.Button_MC2_A0_s1_shift_left.Text = '◀';
            app.Button_MC2_A0_s1_shift_right = uibutton(app.Panel_MC2_camera_A0_compensation, 'push');
            app.Button_MC2_A0_s1_shift_right.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_A0_s1_shift_rightPushed, true);
            app.Button_MC2_A0_s1_shift_right.Enable = 'off';
            app.Button_MC2_A0_s1_shift_right.Text = '▶';
            app.Label_13 = uilabel(app.Panel_MC2_camera_A0_compensation);
            app.Label_13.Text = '第1阶段';
            app.EditField_MC2_A0_s1_offset = uieditfield(app.Panel_MC2_camera_A0_compensation, 'numeric');
            app.EditField_MC2_A0_s1_offset.ValueDisplayFormat = '%11.5g';
            app.EditField_MC2_A0_s1_offset.Editable = 'off';
            app.Button_MC2_A0_s2_shift_left = uibutton(app.Panel_MC2_camera_A0_compensation, 'push');
            app.Button_MC2_A0_s2_shift_left.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_A0_s2_shift_leftPushed, true);
            app.Button_MC2_A0_s2_shift_left.Enable = 'off';
            app.Button_MC2_A0_s2_shift_left.Text = '◀';
            app.Button_MC2_A0_s2_shift_right = uibutton(app.Panel_MC2_camera_A0_compensation, 'push');
            app.Button_MC2_A0_s2_shift_right.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_A0_s2_shift_rightPushed, true);
            app.Button_MC2_A0_s2_shift_right.Enable = 'off';
            app.Button_MC2_A0_s2_shift_right.Text = '▶';
            app.Label_14 = uilabel(app.Panel_MC2_camera_A0_compensation);
            app.Label_14.Text = '第2阶段';
            app.EditField_MC2_A0_s2_offset = uieditfield(app.Panel_MC2_camera_A0_compensation, 'numeric');
            app.EditField_MC2_A0_s2_offset.ValueDisplayFormat = '%11.5g';
            app.EditField_MC2_A0_s2_offset.Editable = 'off';
            app.Button_save_YongXiu_MC2_A0_offset = uibutton(app.Panel_MC2_camera_A0_compensation, 'push');
            app.Button_save_YongXiu_MC2_A0_offset.ButtonPushedFcn = createCallbackFcn(app, @Button_save_YongXiu_MC2_A0_offsetPushed, true);
            app.Button_save_YongXiu_MC2_A0_offset.Enable = 'off';
            app.Button_save_YongXiu_MC2_A0_offset.Text = '参数写入控制器';
            app.Label_16 = uilabel(app.Panel_MC2_camera_A0_compensation);
            app.Label_16.Text = '切换阈值';
            app.EditField_MC2_A0_switch_threshold = uieditfield(app.Panel_MC2_camera_A0_compensation, 'numeric');
            app.EditField_MC2_A0_switch_threshold.ValueDisplayFormat = '%11.5g';
            app.EditField_MC2_A0_switch_threshold.ValueChangedFcn = createCallbackFcn(app, @EditField_MC2_A0_switch_thresholdValueChanged, true);
            app.Tab_reference_trajectory_maintenance = uitab(app.TabGroup);
            app.Tab_reference_trajectory_maintenance.Title = '参考轨迹维护';
            app.Panel_reference_trajectory_update = uipanel(app.Tab_reference_trajectory_maintenance);
            app.Panel_reference_trajectory_update.Tooltip = {'选择专用参考轨迹压缩包更新到控制器中'};
            app.Panel_reference_trajectory_update.Title = '控制器内参考轨迹文件更新';
            app.Button_MC1_reference_trajectory_refresh = uibutton(app.Panel_reference_trajectory_update, 'push');
            app.Button_MC1_reference_trajectory_refresh.ButtonPushedFcn = createCallbackFcn(app, @Button_MC1_reference_trajectory_refreshPushed, true);
            app.Button_MC1_reference_trajectory_refresh.Enable = 'off';
            app.Button_MC1_reference_trajectory_refresh.Text = '轨迹文件写入MC1控制器';
            app.Button_MC2_reference_trajectory_refresh = uibutton(app.Panel_reference_trajectory_update, 'push');
            app.Button_MC2_reference_trajectory_refresh.ButtonPushedFcn = createCallbackFcn(app, @Button_MC2_reference_trajectory_refreshPushed, true);
            app.Button_MC2_reference_trajectory_refresh.Enable = 'off';
            app.Button_MC2_reference_trajectory_refresh.Text = '轨迹文件写入MC2控制器';
            app.Panel_MC1_grid_origin_info = uipanel(app.Tab_reference_trajectory_maintenance);
            app.Panel_MC1_grid_origin_info.Tooltip = {'参考轨迹相对坐标原点经纬度，通常在采集地图之前修改'};
            app.Panel_MC1_grid_origin_info.Title = 'MC1端相对坐标原点';
            app.Label_17 = uilabel(app.Panel_MC1_grid_origin_info);
            app.Label_17.Text = '纬度';
            app.EditField_MC1_grid_origin_latitude = uieditfield(app.Panel_MC1_grid_origin_info, 'numeric');
            app.EditField_MC1_grid_origin_latitude.Limits = [-90 90];
            app.EditField_MC1_grid_origin_latitude.ValueDisplayFormat = '%.11g °';
            app.EditField_MC1_grid_origin_latitude.ValueChangedFcn = createCallbackFcn(app, @EditField_MC1_grid_origin_latitudeValueChanged, true);
            app.EditField_MC1_grid_origin_latitude.Editable = 'off';
            app.EditField_MC1_grid_origin_latitude.Tooltip = {'设置数据记录断点标识，便于回溯数据'};
            app.Label_18 = uilabel(app.Panel_MC1_grid_origin_info);
            app.Label_18.Text = '经度';
            app.EditField_MC1_grid_origin_longitude = uieditfield(app.Panel_MC1_grid_origin_info, 'numeric');
            app.EditField_MC1_grid_origin_longitude.Limits = [-180 180];
            app.EditField_MC1_grid_origin_longitude.ValueDisplayFormat = '%.11g °';
            app.EditField_MC1_grid_origin_longitude.ValueChangedFcn = createCallbackFcn(app, @EditField_MC1_grid_origin_longitudeValueChanged, true);
            app.EditField_MC1_grid_origin_longitude.Editable = 'off';
            app.EditField_MC1_grid_origin_longitude.Tooltip = {'设置数据记录断点标识，便于回溯数据'};
            app.Panel_MC2_grid_origin_info = uipanel(app.Tab_reference_trajectory_maintenance);
            app.Panel_MC2_grid_origin_info.Tooltip = {'参考轨迹相对坐标原点经纬度，通常在采集地图之前修改'};
            app.Panel_MC2_grid_origin_info.Title = 'MC2端相对坐标原点';
            app.Label_19 = uilabel(app.Panel_MC2_grid_origin_info);
            app.Label_19.Text = '纬度';
            app.EditField_MC2_grid_origin_latitude = uieditfield(app.Panel_MC2_grid_origin_info, 'numeric');
            app.EditField_MC2_grid_origin_latitude.Limits = [-90 90];
            app.EditField_MC2_grid_origin_latitude.ValueDisplayFormat = '%.11g °';
            app.EditField_MC2_grid_origin_latitude.ValueChangedFcn = createCallbackFcn(app, @EditField_MC2_grid_origin_latitudeValueChanged, true);
            app.EditField_MC2_grid_origin_latitude.Editable = 'off';
            app.EditField_MC2_grid_origin_latitude.Tooltip = {'设置数据记录断点标识，便于回溯数据'};
            app.Label_20 = uilabel(app.Panel_MC2_grid_origin_info);
            app.Label_20.Text = '经度';
            app.EditField_MC2_grid_origin_longitude = uieditfield(app.Panel_MC2_grid_origin_info, 'numeric');
            app.EditField_MC2_grid_origin_longitude.Limits = [-180 180];
            app.EditField_MC2_grid_origin_longitude.ValueDisplayFormat = '%.11g °';
            app.EditField_MC2_grid_origin_longitude.ValueChangedFcn = createCallbackFcn(app, @EditField_MC2_grid_origin_longitudeValueChanged, true);
            app.EditField_MC2_grid_origin_longitude.Editable = 'off';
            app.EditField_MC2_grid_origin_longitude.Tooltip = {'设置数据记录断点标识，便于回溯数据'};
            app.Panel_reference_trajectory_index = uipanel(app.Tab_reference_trajectory_maintenance);
            app.Panel_reference_trajectory_index.Tooltip = {'设置地图管理单元的全局参考轨迹代号，注意在运行的时候修改！'};
            app.Panel_reference_trajectory_index.Title = '参考轨迹代号设置';
            app.MC1EditFieldLabel_2 = uilabel(app.Panel_reference_trajectory_index);
            app.MC1EditFieldLabel_2.Text = 'MC1';
            app.EditField_MC1_reference_trajectory_index = uieditfield(app.Panel_reference_trajectory_index, 'numeric');
            app.EditField_MC1_reference_trajectory_index.Limits = [0 255];
            app.EditField_MC1_reference_trajectory_index.RoundFractionalValues = 'on';
            app.EditField_MC1_reference_trajectory_index.ValueDisplayFormat = '%d';
            app.EditField_MC1_reference_trajectory_index.ValueChangedFcn = createCallbackFcn(app, @EditField_MC1_reference_trajectory_indexValueChanged, true);
            app.EditField_MC1_reference_trajectory_index.Editable = 'off';
            app.MC2EditFieldLabel_2 = uilabel(app.Panel_reference_trajectory_index);
            app.MC2EditFieldLabel_2.Text = 'MC2';
            app.EditField_MC2_reference_trajectory_index = uieditfield(app.Panel_reference_trajectory_index, 'numeric');
            app.EditField_MC2_reference_trajectory_index.Limits = [0 255];
            app.EditField_MC2_reference_trajectory_index.RoundFractionalValues = 'on';
            app.EditField_MC2_reference_trajectory_index.ValueDisplayFormat = '%d';
            app.EditField_MC2_reference_trajectory_index.ValueChangedFcn = createCallbackFcn(app, @EditField_MC2_reference_trajectory_indexValueChanged, true);
            app.EditField_MC2_reference_trajectory_index.Editable = 'off';
            app.Button_save_reference_trajectory_index = uibutton(app.Panel_reference_trajectory_index, 'push');
            app.Button_save_reference_trajectory_index.ButtonPushedFcn = createCallbackFcn(app, @Button_save_reference_trajectory_indexPushed, true);
            app.Button_save_reference_trajectory_index.Enable = 'off';
            app.Button_save_reference_trajectory_index.Text = '参数写入控制器';
            % Show the figure after all components are created
            app.V14UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ART_ADAS_TOOLS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.V14UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.V14UIFigure)
        end
    end
end