classdef Choose_net_port_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DropDown_tram_type     matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        Button_confirm         matlab.ui.control.Button
        ButtonGroup_cab_index  matlab.ui.container.ButtonGroup
        MC2Button              matlab.ui.control.RadioButton
        MC1Button              matlab.ui.control.RadioButton
        ButtonGroup_net_index  matlab.ui.container.ButtonGroup
        PU300Button            matlab.ui.control.RadioButton
        CN300Button            matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        calling_app     % 调用自身的app对象
        tram_type_index % 车型
        net_index       % 网络索引
        cab_index       % 驾驶室索引
    end
    
    methods (Access = private)
        
        function [] = Confirm_connect_callback(app, ~, event)
            if 1 == event.SelectedOptionIndex
                % 弹起主界面连接按钮
                app.calling_app.Button_connect.Value = false;
                
                % 解锁主界面
                app.calling_app.main_figure_lock = false;
                
                % 将参数传递回主界面，连接目标控制器
                Connect_to_target(app.calling_app, app, app.DropDown_tram_type.Items, app.DropDown_tram_type.ItemsData, app.tram_type_index, app.net_index, app.cab_index);
                
                % 关闭自身
                delete(app)
            elseif 2 == event.SelectedOptionIndex
                uialert(app.UIFigure, '连接请求已撤回，请重新选择连接方式！', '警告', 'Icon', 'warning');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            % 保存调用自身的app对象
            app.calling_app = main_app;
        end

        % Button pushed function: Button_confirm
        function Button_confirmPushed(app, event)
            % 记录所选择的车型索引
            app.tram_type_index = sscanf(app.DropDown_tram_type.Value, '%d');
            
            % 设置需要传递的参数
            if true == app.CN300Button.Value
                app.net_index = 1;
                app.cab_index = 0;
                net_name = app.CN300Button.Text;
                cab_name = '';
            elseif true == app.PU300Button.Value
                app.net_index = 2;
                net_name = app.PU300Button.Text;
                if true == app.MC1Button.Value
                    app.cab_index = 1;
                    cab_name = app.MC1Button.Text;
                elseif true == app.MC2Button.Value
                    app.cab_index = 2;
                    cab_name = app.MC2Button.Text;
                end
            end
            
            if 0 == app.tram_type_index
                uialert(app.UIFigure, '请选择一种车型！', '警告', 'Icon', 'warning');
            else
                % 弹出操作确认提示框
                confirm_message = sprintf('确认连接到%s%s%s吗?', app.DropDown_tram_type.Items{app.tram_type_index + 1}, cab_name, net_name);
                uiconfirm(app.UIFigure, confirm_message, '操作确认', 'Options', {'确认', '取消'},'Icon', 'warning', 'CloseFcn', @(src, event)Confirm_connect_callback(app, src, event));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 弹起主界面连接按钮
            app.calling_app.Button_connect.Value = false;
            
            % 解锁主界面
            app.calling_app.main_figure_lock = false;
            
            % 关闭自身
            delete(app)
        end

        % Selection changed function: ButtonGroup_net_index
        function ButtonGroup_net_indexSelectionChanged(app, event)
            selected_button = app.ButtonGroup_net_index.SelectedObject;
            if strcmp(selected_button.Text, 'PU300主控板上的网口')
                app.MC1Button.Enable = true;
                app.MC2Button.Enable = true;
            else
                app.MC1Button.Enable = false;
                app.MC2Button.Enable = false;
            end
        end

        % Value changed function: DropDown_tram_type
        function DropDown_tram_typeValueChanged(app, event)
            selected_tram_type = app.DropDown_tram_type.Value;
            if strcmp(selected_tram_type, '00-Null')
                app.CN300Button.Enable = false;
                app.PU300Button.Enable = false;
                app.MC1Button.Enable = false;
                app.MC2Button.Enable = false;
            else
                app.CN300Button.Enable = true;
                app.PU300Button.Enable = true;
                selected_button = app.ButtonGroup_net_index.SelectedObject;
                if strcmp(selected_button.Text, 'PU300主控板上的网口')
                    app.MC1Button.Enable = true;
                    app.MC2Button.Enable = true;
                else
                    app.MC1Button.Enable = false;
                    app.MC2Button.Enable = false;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '请确认物理连接信息';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ButtonGroup_net_index = uibuttongroup(app.UIFigure);
            app.ButtonGroup_net_index.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_net_indexSelectionChanged, true);
            app.ButtonGroup_net_index.Title = '选择网口';
            app.CN300Button = uiradiobutton(app.ButtonGroup_net_index);
            app.CN300Button.Enable = 'off';
            app.CN300Button.Text = 'CN300交换板上的网口';
            app.CN300Button.Value = true;
            app.PU300Button = uiradiobutton(app.ButtonGroup_net_index);
            app.PU300Button.Enable = 'off';
            app.PU300Button.Text = 'PU300主控板上的网口';
            app.ButtonGroup_cab_index = uibuttongroup(app.UIFigure);
            app.ButtonGroup_cab_index.Title = '选择车头';
            app.MC1Button = uiradiobutton(app.ButtonGroup_cab_index);
            app.MC1Button.Enable = 'off';
            app.MC1Button.Text = 'MC1端';
            app.MC1Button.Value = true;
            app.MC2Button = uiradiobutton(app.ButtonGroup_cab_index);
            app.MC2Button.Enable = 'off';
            app.MC2Button.Text = 'MC2端';
            app.Button_confirm = uibutton(app.UIFigure, 'push');
            app.Button_confirm.ButtonPushedFcn = createCallbackFcn(app, @Button_confirmPushed, true);
            app.Button_confirm.Text = '确认';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择车型';
            app.DropDown_tram_type = uidropdown(app.UIFigure);
            app.DropDown_tram_type.Items = {'', '一代平台-株洲T1(T2)车', '一代平台-株洲T3车', '一代平台-永修车', '一代平台-宜宾车', '二代平台-使命号', '二代平台-西安车', '一代平台-宜宾车-方向机双CAN'};
            app.DropDown_tram_type.ItemsData = {'00-Null', '01-ZhuZhouT1T2', '02-ZhuZhouT3', '03-YongXiu', '04-YiBin', '05-ShiMingHao', '06-XiAn', '07-YiBin2CAN'};
            app.DropDown_tram_type.ValueChangedFcn = createCallbackFcn(app, @DropDown_tram_typeValueChanged, true);
            app.DropDown_tram_type.Value = '00-Null';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Choose_net_port_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end