classdef SQLite_editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SQLiteV01UIFigure        matlab.ui.Figure
        EditField_search         matlab.ui.control.EditField
        Label_6                  matlab.ui.control.Label
        DropDown_database        matlab.ui.control.DropDown
        Label_5                  matlab.ui.control.Label
        ListBox_carriage         matlab.ui.control.ListBox
        ctrlLabel_2              matlab.ui.control.Label
        ListBox_tram_type        matlab.ui.control.ListBox
        ctrlLabel                matlab.ui.control.Label
        DropDown_query_table     matlab.ui.control.DropDown
        Label_2                  matlab.ui.control.Label
        DropDown_original_table  matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        ContextMenu              matlab.ui.container.ContextMenu
        Menu_modify_item         matlab.ui.container.Menu
        Menu_create_item         matlab.ui.container.Menu
        Menu_delete_item         matlab.ui.container.Menu
        Menu_refresh_table       matlab.ui.container.Menu
        ExcelMenu                matlab.ui.container.Menu
    end


    properties (Access = public)
        SQLite_manager_obj            % SQLite数据库管理器实例
        original_table_current        % 当前原始表
        query_table_current           % 当前查询表
        joint_query_table_current     % 当前合并同类项后的查询表
        selected_indices              % 当前UI表中选中的行号
        original_table_chinese_name   % 原始表的中文名
        original_table_english_name   % 原始表的英文名
        query_table_chinese_name      % 查询表的中文名
        
        % 数据库表名称清单
        database_table_name_list = readtable('Database_properties_APC_config.xlsx', 'PreserveVariableNames', true, 'Sheet', '表名称清单');

        % 数据库定制查询名称清单
        database_query_name_list = readtable('Database_properties_APC_config.xlsx', 'PreserveVariableNames', true, 'Sheet', '查询列表');
    end

    methods (Access = public)
        % 针对车型、车厢多选框数值改变，对应修改UI表格显示内容
        function [] = Tram_type_and_carriage_change(app)
            % 获取当前车型、车厢多选框选中内容
            tram_type_value = app.ListBox_tram_type.Value;
            carriage_value = app.ListBox_carriage.Value;

            % 在当前查询表非空时，根据选择的车型筛选显示表格内容
            if ~isempty(app.query_table_current)
                % 选项为空时，载入全部可选列表，相当于不筛选
                if isempty(tram_type_value)
                    tram_type_value = app.ListBox_tram_type.Items;
                end
                if isempty(carriage_value)
                    carriage_value = app.ListBox_carriage.Items;
                end

                % 根据选型内容筛选
                temp_table = app.query_table_current(ismember(app.query_table_current.('tram_type'), tram_type_value) & ismember(app.query_table_current.('carriage'), carriage_value), :);

                % 根据主索引合并同类项的车型、车厢
                [app.joint_query_table_current, ~, ic] = unique(temp_table(:, 1 : end - 2));
                for index = 1 : height(app.joint_query_table_current)
                    % 筛选同类项的车型、车厢描述列表
                    temp_tram_type = unique(string(temp_table{ismember(ic, index), {'tram_type'}}));
                    temp_carriage = unique(string(temp_table{ismember(ic, index), {'carriage'}}));

                    % 合并描述
                    app.joint_query_table_current.('tram_type')(index) = join(transpose(temp_tram_type), string([',', newline]));
                    app.joint_query_table_current.('carriage')(index) = join(transpose(temp_carriage), string([',', newline]));
                end

                % 针对搜索框关键词，对应修改UI表格显示内容
                app.Key_word_search_table;
            end
        end

        % 针对搜索框关键词，对应修改UI表格显示内容
        function [] = Key_word_search_table(app)
            % 获取搜索框当前内容
            value = app.EditField_search.Value;

            % 挑选当前非空的表格作为数据源表格
            if ~isempty(app.original_table_current)
                source_table = app.original_table_current;
            elseif ~isempty(app.joint_query_table_current)
                source_table = app.joint_query_table_current;
            else
                source_table = table.empty;
            end

            % 如果数据源表格非空，在其中筛选符合搜索框关键词的条目
            if ~isempty(source_table)
                % 拆解搜索框当前字符串，以便细分关键词筛选
                key_word = strsplit(value);

                % 对于查询表，舍弃最后2列（车型、车厢）参与搜索
                if ~isempty(app.joint_query_table_current)
                    max_column = width(source_table) - 2;
                else
                    max_column = width(source_table);
                end

                % 综合考虑UI表中已选择的列与最大列数缩小搜索范围
                if ~isempty(app.selected_indices)
                    selected_column = unique(app.selected_indices(:, 2));
                    selected_column_range = selected_column(max_column >= selected_column);
                    if isempty(selected_column_range)
                        selected_column_range = 1 : max_column;
                    end
                else
                    selected_column_range = 1 : max_column;
                end
                search_table = source_table(:, selected_column_range);

                % 将table转cell转string筛选条目
                search_result = contains(string(table2cell(search_table)), key_word);

                % 在某一行有任意不为0的元素，代表这一行符合搜索关键词，如果搜索结果只有1列则直接作为匹配索引
                if 1 < size(search_result, 2)
                    matched_index = transpose(any(transpose(search_result)));
                else
                    matched_index = search_result;
                end

                % 显示搜索结果表格
                app.UITable.Data = source_table(matched_index, :);
            end
        end
        
        % 刷新UI表格内容
        function [] = UI_table_refresh(app)
            % 清空选择的索引
            app.selected_indices = [];

            % 当前是原始表则刷新原始表内容
            if ~isempty(app.original_table_english_name)
                % 重新导出原始表
                app.original_table_current = app.SQLite_manager_obj.Database_table_export(app.original_table_english_name);

                % 刷新UI表显示内容
                app.UITable.Data = app.original_table_current;
            end
            
            % 当前是查询表则刷新查询表内容
            if ~isempty(app.query_table_chinese_name)
                % 重新导出查询表
                app.query_table_current = app.SQLite_manager_obj.Database_query_export(app.query_table_chinese_name);
                
                % 根据车型、车厢、搜索框筛选条件显示UI表格内容
                app.Tram_type_and_carriage_change;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % UI表格初始化为空
            app.UITable.ColumnName = {''};
            app.UITable.Data = table.empty;

            % SQLite数据库管理器实例初始化
            app.SQLite_manager_obj = SQLiteManager(which('ART_IDS_APC_config.db'));

            % 连接数据库
            app.SQLite_manager_obj = app.SQLite_manager_obj.Database_connect;

            % 获取数据库里所有表的名称，转换为中文名后更新到原始表下拉菜单中，第一项留空
            table_name_list = app.SQLite_manager_obj.Database_table_name_list_export;
            [~, matched_index] = ismember(table_name_list, app.database_table_name_list.('英文名'));
            chinese_name_list = app.database_table_name_list.('中文名')(matched_index);
            app.DropDown_original_table.Items = [{''}; chinese_name_list];

            % 将数据库定制查询名称清单的中文名更新到定制查询表下拉菜单中，第一项留空
            app.DropDown_query_table.Items = [{''}; app.database_query_name_list.('中文名')];

            % 将数据库车型列表查询到的车型列表更新到车型多选框中
            tram_type_table = app.SQLite_manager_obj.Database_table_export('tram_type_list');
            app.ListBox_tram_type.Items = tram_type_table.('tram_type');

            % 将数据库车厢列表查询到的车厢列表更新到车厢多选框中
            carriage_table = app.SQLite_manager_obj.Database_table_export('carriage_list');
            app.ListBox_carriage.Items = carriage_table.('carriage');
        end

        % Close request function: SQLiteV01UIFigure
        function SQLiteV01UIFigureCloseRequest(app, event)
            % 断开数据库连接
            app.SQLite_manager_obj = app.SQLite_manager_obj.Database_disconnect;

            delete(app)
        end

        % Value changed function: DropDown_original_table
        function DropDown_original_tableValueChanged(app, event)
            app.original_table_chinese_name = app.DropDown_original_table.Value;

            % 清空选择的索引
            app.selected_indices = [];
            
            % 清空搜索框
            app.EditField_search.Value = '';

            % 清空当前显示查询表下拉菜单
            app.DropDown_query_table.Value = '';

            % 清空当前查询表、合并同类项后的查询表
            app.query_table_current = table.empty;
            app.joint_query_table_current = table.empty;
            app.query_table_chinese_name = '';

            % 选项非空时导出数据库原始表内容展示在UI表格中，否则清空UI表格
            if ~isequal('', app.original_table_chinese_name)
                % 禁用车型、车厢筛选列表
                app.ListBox_tram_type.Enable = false;
                app.ListBox_carriage.Enable = false;

                % 搜索中文表名称名对应的英文名
                app.original_table_english_name = app.database_table_name_list.('英文名')(ismember(app.database_table_name_list.('中文名'), app.original_table_chinese_name));
                app.original_table_current = app.SQLite_manager_obj.Database_table_export(app.original_table_english_name);

                % 载入对应中文名Sheet页的内容
                table_properties = readtable('Database_properties_APC_config.xlsx', 'PreserveVariableNames', true, 'Sheet', app.original_table_chinese_name);
                [~, matched_index] = ismember(app.original_table_current.Properties.VariableNames, table_properties.('英文名'));

                % 提取中文名作为UI表头
                app.UITable.ColumnName = table_properties.('中文名')(matched_index);

                % 载入数据库原始表内容到UI表中显示
                app.UITable.Data = app.original_table_current;
            else
                app.UITable.ColumnName = {''};
                app.UITable.Data = table.empty;
                app.original_table_english_name = '';
            end
        end

        % Value changed function: DropDown_query_table
        function DropDown_query_tableValueChanged(app, event)
            app.query_table_chinese_name = app.DropDown_query_table.Value;

            % 清空选择的索引
            app.selected_indices = [];
            
            % 清空搜索框
            app.EditField_search.Value = '';

            % 清空当前显示原始表下拉菜单
            app.DropDown_original_table.Value = '';

            % 清空当前原始表
            app.original_table_current = table.empty;
            app.original_table_chinese_name = '';
            app.original_table_english_name = '';

            % 选项非空时导出数据库定制查询表内容展示在UI表格中，否则清空UI表格
            if ~isequal('', app.query_table_chinese_name)
                % 启用车型、车厢筛选列表
                app.ListBox_tram_type.Enable = true;
                app.ListBox_carriage.Enable = true;

                % 导出数据库定制查询表内容
                app.query_table_current = app.SQLite_manager_obj.Database_query_export(app.query_table_chinese_name);

                % 载入对应中文名Sheet页的内容
                table_properties = readtable('Database_properties_APC_config.xlsx', 'PreserveVariableNames', true, 'Sheet', app.query_table_chinese_name);
                [~, matched_index] = ismember(app.query_table_current.Properties.VariableNames, table_properties.('英文名'));

                % 提取中文名作为UI表头
                app.UITable.ColumnName = table_properties.('中文名')(matched_index);

                % 根据主索引合并同类项的车型、车厢
                [app.joint_query_table_current, ~, ic] = unique(app.query_table_current(:, 1 : end - 2));
                for index = 1 : height(app.joint_query_table_current)
                    % 筛选同类项的车型、车厢描述列表
                    temp_tram_type = unique(string(app.query_table_current{ismember(ic, index), {'tram_type'}}));
                    temp_carriage = unique(string(app.query_table_current{ismember(ic, index), {'carriage'}}));

                    % 合并描述
                    app.joint_query_table_current.('tram_type')(index) = join(transpose(temp_tram_type), string([',', newline]));
                    app.joint_query_table_current.('carriage')(index) = join(transpose(temp_carriage), string([',', newline]));
                end

                % 根据车型、车厢筛选结果显示UI表内容
                app.Tram_type_and_carriage_change;
            else
                app.UITable.ColumnName = {''};
                app.UITable.Data = table.empty;
                app.query_table_current = table.empty;
                app.joint_query_table_current = table.empty;
            end
        end

        % Value changed function: ListBox_tram_type
        function ListBox_tram_typeValueChanged(app, event)
            app.Tram_type_and_carriage_change;
        end

        % Value changed function: ListBox_carriage
        function ListBox_carriageValueChanged(app, event)
            app.Tram_type_and_carriage_change;
        end

        % Value changed function: DropDown_database
        function DropDown_databaseValueChanged(app, event)
            value = app.DropDown_database.Value;
            switch(value)
                case '1'
                    % 智轨电车循迹控制应用协议编解码配置数据库
                    database_file = 'ART_IDS_APC_config.db';

                case '2'
                    % 智轨电车地图管理应用协议编解码配置数据库
                    database_file = 'ART_MMU_APC_config.db';

                otherwise
                    % 未定义的选项
                    error('数据库选项代号异常！');
            end

            % 断开数据库连接
            app.SQLite_manager_obj = app.SQLite_manager_obj.Database_disconnect;

            % 更换SQLite数据库
            app.SQLite_manager_obj = SQLiteManager(which(database_file));

            % 连接数据库
            app.SQLite_manager_obj = app.SQLite_manager_obj.Database_connect;
        end

        % Value changed function: EditField_search
        function EditField_searchValueChanged(app, event)
            app.Key_word_search_table;
        end

        % Menu selected function: Menu_modify_item
        function Menu_modify_itemSelected(app, event)
            % 如果没有选择UI表中的有效条目，提示重新选择，否则打开对应的编辑器
            if isempty(app.selected_indices)
                uialert(app.SQLiteV01UIFigure, '未选择表格中的有效条目，请重新用鼠标左键选择！', '警告', 'Icon', 'warning');
            else
                % 原始表非空时，启动单一表编辑器
                if ~isempty(app.original_table_current)
                    Single_table_editor(app, 'update');
                else
                    uialert(app.SQLiteV01UIFigure, '原始表为空，无条目可修改！', '警告', 'Icon', 'warning');
                end
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % 记录选择的索引
            app.selected_indices = event.Indices;
        end

        % Menu selected function: Menu_create_item
        function Menu_create_itemSelected(app, event)
            % 原始表选项非空时，启动单一表编辑器
            if ~isempty(app.original_table_chinese_name)
                Single_table_editor(app, 'insert');
            else
                uialert(app.SQLiteV01UIFigure, '暂不支持对定制查询表的插入！', '警告', 'Icon', 'warning');
            end
        end

        % Menu selected function: Menu_delete_item
        function Menu_delete_itemSelected(app, event)
            % 如果没有选择UI表中的有效条目，提示重新选择，否则打开对应的编辑器
            if isempty(app.selected_indices)
                uialert(app.SQLiteV01UIFigure, '未选择表格中的有效条目，请重新用鼠标左键选择！', '警告', 'Icon', 'warning');
            else
                % 原始表非空时，从数据库中删除选定条目
                if ~isempty(app.original_table_current)
                    % 遍历选择的主索引，删除数据库中对应的记录
                    for index = 1 : size(app.selected_indices, 1)
                        % 如果当前原始表的表头包含ID这一列，为普通表，否则为关系表
                        if matches('ID', app.original_table_current.Properties.VariableNames)
                            primary_ID = app.original_table_current.('ID')(app.selected_indices(index, 1));
                            app.SQLite_manager_obj.Database_normal_table_record_delete(app.original_table_english_name, primary_ID);
                        else
                            primary_ID = app.original_table_current{:, 1}(app.selected_indices(index, 1));
                            tram_type_ID = app.original_table_current{:, 2}(app.selected_indices(index, 1));
                            carriage_ID = app.original_table_current{:, 3}(app.selected_indices(index, 1));
                            app.SQLite_manager_obj.Database_relationship_table_record_delete(app.original_table_english_name, primary_ID, tram_type_ID, carriage_ID);
                        end
                    end
                    
                    % 刷新UI表格内容
                    app.UI_table_refresh;
                else
                    uialert(app.SQLiteV01UIFigure, '原始表为空，无条目可删除！', '警告', 'Icon', 'warning');
                end
            end
        end

        % Menu selected function: Menu_refresh_table
        function Menu_refresh_tableSelected(app, event)
            app.UI_table_refresh;
        end

        % Menu selected function: ExcelMenu
        function ExcelMenuSelected(app, event)
            % UI表格非空时，将其内容写入外部Excel文件
            if ~isempty(app.UITable.Data)
                % 设置默认文件名
                default_name = '';
                if ~isempty(app.original_table_chinese_name)
                    default_name = char(app.original_table_chinese_name);
                end
                if ~isempty(app.query_table_chinese_name)
                    default_name = char(app.query_table_chinese_name);
                end

                % 设置目标Excel文件名
                [selected_file_name, selected_file_path] = uiputfile({'*.xlsx', 'Excel files (*.xlsx)'}, '请设置目标Excel文件名', default_name);
                if isequal(0, selected_file_name)
                    uialert(app.SQLiteV01UIFigure, '未设置目标Excel文件名！', '警告', 'Icon', 'warning');
                    return;
                end
                
                % 如果没有选择UI表中的有效条目，导出全部内容，否则只导出选中的行
                if isempty(app.selected_indices)
                    output_table = app.UITable.Data;
                else
                    selected_row = unique(app.selected_indices(:, 1));
                    output_table = app.UITable.Data(selected_row, :);
                end
                
                % 将UI表格表头替换为中文后与其内容一并写入所设置的目标Excel文件
                output_table.Properties.VariableNames = app.UITable.ColumnName;
                writetable(output_table, fullfile(selected_file_path, selected_file_name), 'WriteMode', 'replacefile');
            else
                uialert(app.SQLiteV01UIFigure, 'UI表格无内容可供导出！', '警告', 'Icon', 'warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SQLiteV01UIFigure = uifigure('Visible', 'off');
            app.SQLiteV01UIFigure.Name = '智轨电车SQLite数据库编辑工具 V0.1';
            app.SQLiteV01UIFigure.Icon = '中车LOGO.png';
            app.SQLiteV01UIFigure.CloseRequestFcn = createCallbackFcn(app, @SQLiteV01UIFigureCloseRequest, true);
            app.UITable = uitable(app.SQLiteV01UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Label = uilabel(app.SQLiteV01UIFigure);
            app.Label.Text = '当前显示原始表';
            app.DropDown_original_table = uidropdown(app.SQLiteV01UIFigure);
            app.DropDown_original_table.Items = {};
            app.DropDown_original_table.ValueChangedFcn = createCallbackFcn(app, @DropDown_original_tableValueChanged, true);
            app.DropDown_original_table.Value = {};
            app.Label_2 = uilabel(app.SQLiteV01UIFigure);
            app.Label_2.Text = '当前显示定制查询表';
            app.DropDown_query_table = uidropdown(app.SQLiteV01UIFigure);
            app.DropDown_query_table.Items = {};
            app.DropDown_query_table.ValueChangedFcn = createCallbackFcn(app, @DropDown_query_tableValueChanged, true);
            app.DropDown_query_table.Value = {};
            app.ctrlLabel = uilabel(app.SQLiteV01UIFigure);
            app.ctrlLabel.Text = {'车型筛选列表'; '（按住ctrl多选'; '或取消选中）'};
            app.ListBox_tram_type = uilistbox(app.SQLiteV01UIFigure);
            app.ListBox_tram_type.Items = {};
            app.ListBox_tram_type.Multiselect = 'on';
            app.ListBox_tram_type.ValueChangedFcn = createCallbackFcn(app, @ListBox_tram_typeValueChanged, true);
            app.ListBox_tram_type.Enable = 'off';
            app.ListBox_tram_type.Value = {};
            app.ctrlLabel_2 = uilabel(app.SQLiteV01UIFigure);
            app.ctrlLabel_2.Text = {'车厢筛选列表'; '（按住ctrl多选'; '或取消选中）'};
            app.ListBox_carriage = uilistbox(app.SQLiteV01UIFigure);
            app.ListBox_carriage.Items = {};
            app.ListBox_carriage.Multiselect = 'on';
            app.ListBox_carriage.ValueChangedFcn = createCallbackFcn(app, @ListBox_carriageValueChanged, true);
            app.ListBox_carriage.Enable = 'off';
            app.ListBox_carriage.Value = {};
            app.Label_5 = uilabel(app.SQLiteV01UIFigure);
            app.Label_5.Text = '当前数据库';
            app.DropDown_database = uidropdown(app.SQLiteV01UIFigure);
            app.DropDown_database.Items = {'智轨电车循迹控制应用协议编解码配置数据库', '智轨电车地图管理应用协议编解码配置数据库'};
            app.DropDown_database.ItemsData = {'1', '2'};
            app.DropDown_database.ValueChangedFcn = createCallbackFcn(app, @DropDown_databaseValueChanged, true);
            app.DropDown_database.Value = '1';
            app.Label_6 = uilabel(app.SQLiteV01UIFigure);
            app.Label_6.Text = {'在下表中搜索关键词'; '（多个可用空格分隔）'};
            app.EditField_search = uieditfield(app.SQLiteV01UIFigure, 'text');
            app.EditField_search.ValueChangedFcn = createCallbackFcn(app, @EditField_searchValueChanged, true);
            app.ContextMenu = uicontextmenu(app.SQLiteV01UIFigure);
            app.Menu_modify_item = uimenu(app.ContextMenu);
            app.Menu_modify_item.MenuSelectedFcn = createCallbackFcn(app, @Menu_modify_itemSelected, true);
            app.Menu_modify_item.Text = '修改选定条目';
            app.Menu_create_item = uimenu(app.ContextMenu);
            app.Menu_create_item.MenuSelectedFcn = createCallbackFcn(app, @Menu_create_itemSelected, true);
            app.Menu_create_item.Text = '插入新条目';
            app.Menu_delete_item = uimenu(app.ContextMenu);
            app.Menu_delete_item.MenuSelectedFcn = createCallbackFcn(app, @Menu_delete_itemSelected, true);
            app.Menu_delete_item.Text = '删除选定条目';
            app.Menu_refresh_table = uimenu(app.ContextMenu);
            app.Menu_refresh_table.MenuSelectedFcn = createCallbackFcn(app, @Menu_refresh_tableSelected, true);
            app.Menu_refresh_table.Text = '刷新表格';
            app.ExcelMenu = uimenu(app.ContextMenu);
            app.ExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcelMenuSelected, true);
            app.ExcelMenu.Text = '导出为Excel';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.SQLiteV01UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SQLite_editor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SQLiteV01UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SQLiteV01UIFigure)
        end
    end
end