classdef Single_table_editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label                          matlab.ui.control.Label
        Button_insert_database_record  matlab.ui.control.Button
        Button_update_database         matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
    end


    properties (Access = public)
        calling_app                   % 调用本界面的主app对象
        modified_database             % 被修改的数据库
        modified_table_chinese_name   % 被修改表的中文名
        modified_table_english_name   % 被修改表的英文名
        original_table                % 开启本界面时传入的原始表格数据
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, action)
            % 提醒操作注意事项
            uialert(app.UIFigure, '此为直接编辑原始表，请确认您已知晓正确的操作方法！', '警告', 'Icon', 'warning');
            
            % 记录调用的本界面的主界面app对象
            app.calling_app = main_app;

            % 记录被修改的数据库
            app.modified_database = app.calling_app.SQLite_manager_obj;

            % 记录被修改表的中文名
            app.modified_table_chinese_name = app.calling_app.DropDown_original_table.Value;

            % 打开保存了APC配置数据库属性信息的模板表格文件
            template_table_file = 'Database_properties_APC_config.xlsx';

            % 载入模板表格中的表名称清单
            table_name_list = readtable(template_table_file, 'PreserveVariableNames', ...
                true, 'Sheet', '表名称清单');

            % 根据中文名获取对应英文名
            app.modified_table_english_name = string(table_name_list.('英文名')(ismember(...
                table_name_list.('中文名'), app.modified_table_chinese_name)));

            % 记录开启本界面时传入的原始表格数据
            if ~isempty(app.calling_app.selected_indices)
                selected_row = unique(app.calling_app.selected_indices(:, 1));
                app.original_table = app.calling_app.UITable.Data(selected_row, :);
            else
                app.original_table = app.calling_app.UITable.Data;
            end

            % 显示主界面中被选中的表格列名
            app.UITable.ColumnName = app.calling_app.UITable.ColumnName;

            % 根据所要传入的动作选择参数执行对应的操作
            switch(action)
                case 'update'
                    % 显示主界面中被选中的表格内容
                    app.UITable.Data = app.original_table;

                    % 设置UI表格第一列为不可编辑
                    app.UITable.ColumnEditable = [false, true(1, width(app.UITable.Data) - 1)];

                    % 使能更新按钮
                    app.Button_update_database.Enable = true;

                case 'insert'
                    % 在UI表中显示预留空间（1000行）的空白表格
                    app.UITable.Data = table('Size', [1000, size(app.UITable.ColumnName, 1)], 'VariableTypes', repmat("cellstr", 1, size(app.UITable.ColumnName, 1)), 'VariableNames', app.original_table.Properties.VariableNames);

                    % 设置UI表格第一列为不可编辑
                    app.UITable.ColumnEditable = [false, true(1, width(app.UITable.Data) - 1)];
                    
                    % 使能插入按钮
                    app.Button_insert_database_record.Enable = true;
                    
                otherwise
                    error('未定义的动作参数！');
            end
                        
        end

        % Button pushed function: Button_update_database
        function Button_update_databasePushed(app, event)
            % 对比当前UI表格内容与预存的原始表格内容的差异
            [modified_row, modified_column] = find(~strcmp(string(table2cell(app.original_table)), string(table2cell(app.UITable.Data))));

            % 遍历修改过的单元格
            if ~isempty(modified_row)
                for index = 1 : length(modified_row)
                    % 解析修改的内容
                    modified_content = string(app.UITable.Data{modified_row(index), modified_column(index)});

                    % 解析字段名
                    field_name = app.UITable.Data.Properties.VariableNames{modified_column(index)};

                    % 解析对应的主索引
                    primary_ID = app.UITable.Data.('ID')(modified_row(index));

                    % 更新数据库记录内容
                    app.modified_database.Database_record_update(app.modified_table_english_name, field_name, primary_ID, modified_content);
                end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 如果提取关闭了主界面，则断开数据库连接
            if isempty(app.calling_app)
                app.modified_database = app.modified_database.Database_disconnect;
            end

            delete(app)
        end

        % Button pushed function: Button_insert_database_record
        function Button_insert_database_recordPushed(app, event)
            % 根据不为空的单元格分布的行确定需要校验的行数范围
            matched_results = ~cellfun(@isempty, table2cell(app.UITable.Data));
            matched_row = transpose(any(transpose(matched_results)));
            
            % 检验是否填写了内容
            if any(matched_row)
                % 检验指定行数范围内的是否全部非空（第一列主索引除外）
                if isempty(find(~matched_results(matched_row, 2 : end), 1))
                    % 根据指定行数范围生成待插入的表格（第一列主索引除外）
                    insert_table = app.UITable.Data(matched_row, 2 : end);
                    
                    % 向数据库插入表格内容
                    app.modified_database.Database_record_insert(app.modified_table_english_name, insert_table);
                else
                    uialert(app.UIFigure, '未检测到填写完整的行，请补全信息！', '警告', 'Icon', 'warning');
                    return;
                end
            else
                uialert(app.UIFigure, '未填写任何内容，无效！', '警告', 'Icon', 'warning');
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '单一表编辑器';
            app.UIFigure.Icon = '中车LOGO.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Button_update_database = uibutton(app.UIFigure, 'push');
            app.Button_update_database.ButtonPushedFcn = createCallbackFcn(app, @Button_update_databasePushed, true);
            app.Button_update_database.Enable = 'off';
            app.Button_update_database.Text = '更新修改到数据库';
            app.Button_insert_database_record = uibutton(app.UIFigure, 'push');
            app.Button_insert_database_record.ButtonPushedFcn = createCallbackFcn(app, @Button_insert_database_recordPushed, true);
            app.Button_insert_database_record.Enable = 'off';
            app.Button_insert_database_record.Text = '在数据库中插入下表条目';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '下表中的主索引由机器自动生成，不可编辑！！！';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Single_table_editor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end